classdef windEmulatorStep4App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WindEmulatorFigure              matlab.ui.Figure
        ShaftSignalsPanel               matlab.ui.container.Panel
        ShaftSpeedEditField             matlab.ui.control.NumericEditField
        ShaftSpeedGauge                 matlab.ui.control.Gauge
        ShaftSpeedGaugeLabel            matlab.ui.control.Label
        ShaftTorqueGauge                matlab.ui.control.Gauge
        ShaftTorqueGaugeLabel           matlab.ui.control.Label
        ShaftTorqueEditField            matlab.ui.control.NumericEditField
        SIDControlPanel                 matlab.ui.container.Panel
        FileTorqueNowEditField          matlab.ui.control.NumericEditField
        TorqueNowEditFieldLabel         matlab.ui.control.Label
        FileSpeedNowEditField           matlab.ui.control.NumericEditField
        SpeedNowEditFieldLabel          matlab.ui.control.Label
        FileCaseNowEditField            matlab.ui.control.NumericEditField
        CaseNowLabel                    matlab.ui.control.Label
        FileProgressEditField           matlab.ui.control.NumericEditField
        ProgressEditFieldLabel          matlab.ui.control.Label
        FileIndexNowEditField           matlab.ui.control.NumericEditField
        IndexNowLabel                   matlab.ui.control.Label
        FileNoSamplesEditField          matlab.ui.control.NumericEditField
        NoSamplesEditFieldLabel         matlab.ui.control.Label
        FromFileLabel                   matlab.ui.control.Label
        SpeedSpinner                    matlab.ui.control.Spinner
        SpeedSpinnerLabel               matlab.ui.control.Label
        ManualControlInputsLabel        matlab.ui.control.Label
        TorqueSpinner                   matlab.ui.control.Spinner
        TorqueSpinnerLabel              matlab.ui.control.Label
        SIDTypeButtonGroup              matlab.ui.container.ButtonGroup
        FromFileButton                  matlab.ui.control.RadioButton
        ManualButton                    matlab.ui.control.RadioButton
        OffButton_2                     matlab.ui.control.RadioButton
        ExperimentOverviewPanel         matlab.ui.container.Panel
        ResetSIDIntLamp                 matlab.ui.control.Lamp
        ResetSIDIntLampLabel            matlab.ui.control.Label
        ResetHILIntLamp                 matlab.ui.control.Lamp
        ResetHILIntLampLabel            matlab.ui.control.Label
        RunSIDLamp                      matlab.ui.control.Lamp
        RunSIDLampLabel                 matlab.ui.control.Label
        RunHILLamp                      matlab.ui.control.Lamp
        RunHILLampLabel                 matlab.ui.control.Label
        RunTimeEditField                matlab.ui.control.NumericEditField
        RunTimeEditFieldLabel           matlab.ui.control.Label
        StopRunButton                   matlab.ui.control.Button
        StartRunButton                  matlab.ui.control.Button
        ExperimentTypeButtonGroup       matlab.ui.container.ButtonGroup
        HILButton                       matlab.ui.control.RadioButton
        SIDButton                       matlab.ui.control.RadioButton
        OffButton                       matlab.ui.control.RadioButton
        RunCounterEditField             matlab.ui.control.NumericEditField
        RunCounterEditFieldLabel        matlab.ui.control.Label
        ExpTimeEditField                matlab.ui.control.NumericEditField
        ExpTimeEditFieldLabel           matlab.ui.control.Label
        RampSlider                      matlab.ui.control.Slider
        RampSliderLabel                 matlab.ui.control.Label
        HPTOInternalsPanel              matlab.ui.container.Panel
        HMOutputPumplmGauge             matlab.ui.control.SemicircularGauge
        HMOutputPumplmGaugeLabel        matlab.ui.control.Label
        HMOutputShaftTNmGauge           matlab.ui.control.SemicircularGauge
        HMOutputShaftTNmGaugeLabel      matlab.ui.control.Label
        genTorqueCmdNmGauge             matlab.ui.control.SemicircularGauge
        genTorqueCmdNmGaugeLabel        matlab.ui.control.Label
        CtrlSignal2Gauge                matlab.ui.control.SemicircularGauge
        CtrlSignal2GaugeLabel           matlab.ui.control.Label
        CtrlSignal1Gauge                matlab.ui.control.SemicircularGauge
        CtrlSignal1GaugeLabel           matlab.ui.control.Label
        PresbarSlider                   matlab.ui.control.Slider
        PresbarSliderLabel              matlab.ui.control.Label
        HILExperimentControlPanel       matlab.ui.container.Panel
        MinSpeedRefSpinner              matlab.ui.control.Spinner
        MinSpeedRefSpinnerLabel         matlab.ui.control.Label
        WavePeriodSpinner               matlab.ui.control.Spinner
        WavePeriodSpinnerLabel          matlab.ui.control.Label
        WaveAmpSpinner                  matlab.ui.control.Spinner
        WaveAmpSpinnerLabel             matlab.ui.control.Label
        FexAmpEditField                 matlab.ui.control.NumericEditField
        FexAmpEditFieldLabel            matlab.ui.control.Label
        KSpringEditField                matlab.ui.control.NumericEditField
        KSpringEditFieldLabel           matlab.ui.control.Label
        KDampingEditField               matlab.ui.control.NumericEditField
        KDampingEditFieldLabel          matlab.ui.control.Label
        SpeedRefSpinner                 matlab.ui.control.Spinner
        SpeedRefSpinnerLabel            matlab.ui.control.Label
        FexGauge                        matlab.ui.control.Gauge
        FexGaugeLabel                   matlab.ui.control.Label
        FexEditField                    matlab.ui.control.NumericEditField
        ACS800GeneratorPanel            matlab.ui.container.Panel
        acs800TorqueCmdEditField        matlab.ui.control.NumericEditField
        acs800TorqueCmdGauge            matlab.ui.control.Gauge
        TorqueCmdGaugeLabel_2           matlab.ui.control.Label
        acs800PowerDown                 matlab.ui.control.Button
        acs800PowerUp                   matlab.ui.control.Button
        acs800InternalStateTextArea     matlab.ui.control.TextArea
        acs800StatusPanel               matlab.ui.container.Panel
        GridLayout2_2                   matlab.ui.container.GridLayout
        acs800AlarmLamp                 matlab.ui.control.Lamp
        AlarmLampLabel_2                matlab.ui.control.Label
        acs800InhibitedLamp             matlab.ui.control.Lamp
        InhibitedLampLabel_2            matlab.ui.control.Label
        acs800TrippedLamp               matlab.ui.control.Lamp
        TrippedLampLabel_2              matlab.ui.control.Label
        acs800RemoteLamp                matlab.ui.control.Lamp
        RemoteLampLabel_2               matlab.ui.control.Label
        acs800RdyRunLamp                matlab.ui.control.Lamp
        RdyRunLampLabel_2               matlab.ui.control.Label
        acs800RdyOnLamp                 matlab.ui.control.Lamp
        RdyOnLampLabel_2                matlab.ui.control.Label
        CtrlWordEditFieldLabel_2        matlab.ui.control.Label
        acs800CtrlWordEditField         matlab.ui.control.EditField
        StatusWordLabel_2               matlab.ui.control.Label
        acs800StatusWordEditField       matlab.ui.control.EditField
        TorqueActualLabel_2             matlab.ui.control.Label
        acs800TorqueGauge               matlab.ui.control.Gauge
        SpeedGaugeLabel_2               matlab.ui.control.Label
        acs800SpeedGauge                matlab.ui.control.Gauge
        acs800SpeedEditField            matlab.ui.control.NumericEditField
        acs800TorqueEditField           matlab.ui.control.NumericEditField
        ACS880ExcitationHydraulicSimulationPanel  matlab.ui.container.Panel
        acs880SpeedGauge                matlab.ui.control.Gauge
        SpeedLabel                      matlab.ui.control.Label
        acs880TorqueCmdEditField        matlab.ui.control.NumericEditField
        acs880TorqueCmdGauge            matlab.ui.control.Gauge
        TorqueCmdGaugeLabel             matlab.ui.control.Label
        acs880PowerDown                 matlab.ui.control.Button
        acs880PowerUp                   matlab.ui.control.Button
        acs880InternalStateTextArea     matlab.ui.control.TextArea
        StatusPanel                     matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        acs880AlarmLamp                 matlab.ui.control.Lamp
        AlarmLampLabel                  matlab.ui.control.Label
        acs880InhibitedLamp             matlab.ui.control.Lamp
        InhibitedLampLabel              matlab.ui.control.Label
        acs880TrippedLamp               matlab.ui.control.Lamp
        TrippedLampLabel                matlab.ui.control.Label
        acs880RemoteLamp                matlab.ui.control.Lamp
        RemoteLampLabel                 matlab.ui.control.Label
        acs880RdyRunLamp                matlab.ui.control.Lamp
        RdyRunLampLabel                 matlab.ui.control.Label
        acs880RdyOnLamp                 matlab.ui.control.Lamp
        RdyOnLampLabel                  matlab.ui.control.Label
        CtrlWordEditFieldLabel          matlab.ui.control.Label
        acs880CtrlWordEditField         matlab.ui.control.EditField
        StatusWordLabel                 matlab.ui.control.Label
        acs880StatusWordEditField       matlab.ui.control.EditField
        TorqueActualLabel               matlab.ui.control.Label
        acs880TorqueGauge               matlab.ui.control.Gauge
        acs880SpeedEditField            matlab.ui.control.NumericEditField
        acs880TorqueEditField           matlab.ui.control.NumericEditField
        TabGroup                        matlab.ui.container.TabGroup
        ScopesTab                       matlab.ui.container.Tab
        UIAxesSwashPlate                matlab.ui.control.UIAxes
        UIAxesPressure                  matlab.ui.control.UIAxes
        UIAxesTorque                    matlab.ui.control.UIAxes
        UIAxesSpeed                     matlab.ui.control.UIAxes
        HILPowerTab                     matlab.ui.container.Tab
        GeneratorSideLabel              matlab.ui.control.Label
        ExciationSideLabel              matlab.ui.control.Label
        WaveBotLabel                    matlab.ui.control.Label
        GenSideAveragePowerkWEditField  matlab.ui.control.NumericEditField
        ExcSideAveragePowerkWEditField  matlab.ui.control.NumericEditField
        WaveBotAveragePowerkWEditField  matlab.ui.control.NumericEditField
        ExcSideAveragePowerkWGauge      matlab.ui.control.SemicircularGauge
        AveragePowerkWGaugeLabel_3      matlab.ui.control.Label
        ExcSidePowerkWGauge             matlab.ui.control.SemicircularGauge
        PowerkWGaugeLabel_3             matlab.ui.control.Label
        GenSideAveragePowerkWGauge      matlab.ui.control.SemicircularGauge
        AveragePowerkWGaugeLabel_4      matlab.ui.control.Label
        GenSidePowerkWGauge             matlab.ui.control.SemicircularGauge
        PowerkWGaugeLabel_4             matlab.ui.control.Label
        WaveBotAveragePowerkWGauge      matlab.ui.control.SemicircularGauge
        AveragePowerkWGaugeLabel_2      matlab.ui.control.Label
        WaveBotPowerkWGauge             matlab.ui.control.SemicircularGauge
        PowerkWGaugeLabel_2             matlab.ui.control.Label
        HydraulicLabel                  matlab.ui.control.Label
        HydrAveragePowerkWEditField     matlab.ui.control.NumericEditField
        HydrAveragePowerkWGauge         matlab.ui.control.SemicircularGauge
        AveragePowerkWGaugeLabel        matlab.ui.control.Label
        HydrPowerkWGauge                matlab.ui.control.SemicircularGauge
        PowerkWGaugeLabel               matlab.ui.control.Label
        UIAxesPower                     matlab.ui.control.UIAxes
        AC880DriveTab                   matlab.ui.container.Tab
        GridLayout                      matlab.ui.container.GridLayout
        acs880ResetLamp                 matlab.ui.control.Lamp
        ResetLampLabel                  matlab.ui.control.Label
        acs880ExternalCtrlLocLamp       matlab.ui.control.Lamp
        ExternalCtrlLocLabel            matlab.ui.control.Label
        acs880RemoteCMDLamp             matlab.ui.control.Lamp
        RemoteCMDLampLabel              matlab.ui.control.Label
        acs880Inching2Lamp              matlab.ui.control.Lamp
        Inching2LampLabel               matlab.ui.control.Label
        acs880Inching1Lamp              matlab.ui.control.Lamp
        Inching1LampLabel               matlab.ui.control.Label
        acs880RampInZeroLamp            matlab.ui.control.Lamp
        RampInZeroLabel                 matlab.ui.control.Label
        acs880RampHoldLamp              matlab.ui.control.Lamp
        RampHoldLampLabel               matlab.ui.control.Label
        acs880RampOutZeroLamp           matlab.ui.control.Lamp
        RampOutZeroLabel                matlab.ui.control.Label
        acs880EnableOperationLamp       matlab.ui.control.Lamp
        EnableOperationLampLabel        matlab.ui.control.Label
        acs880Off3Lamp                  matlab.ui.control.Lamp
        Off3LampLabel                   matlab.ui.control.Label
        acs880Off2Lamp                  matlab.ui.control.Lamp
        Off2LampLabel                   matlab.ui.control.Label
        acs880Off1Lamp                  matlab.ui.control.Lamp
        Off1LampLabel                   matlab.ui.control.Label
        MotorVoltageEditFieldLabel      matlab.ui.control.Label
        acs880MotorVoltageEditField     matlab.ui.control.NumericEditField
        PowerEditFieldLabel             matlab.ui.control.Label
        acs880PowerEditField            matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel         matlab.ui.control.Label
        acs880FrequencyEditField        matlab.ui.control.NumericEditField
        MotorCurrentEditFieldLabel      matlab.ui.control.Label
        acs880MotorCurrentEditField     matlab.ui.control.NumericEditField
        ACS880EK3443Tab                 matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        acs880L3UEditField              matlab.ui.control.NumericEditField
        L3UEditFieldLabel               matlab.ui.control.Label
        acs880L3VEditField              matlab.ui.control.NumericEditField
        L3VEditFieldLabel               matlab.ui.control.Label
        acs880L2UEditField              matlab.ui.control.NumericEditField
        L2UEditField_2Label             matlab.ui.control.Label
        acs880L2VEditField              matlab.ui.control.NumericEditField
        L2VEditFieldLabel               matlab.ui.control.Label
        acs880L1UEditField              matlab.ui.control.NumericEditField
        L1ULabel                        matlab.ui.control.Label
        acs880L1VEditField              matlab.ui.control.NumericEditField
        L1VEditFieldLabel               matlab.ui.control.Label
        acs880L3L1VEditField            matlab.ui.control.NumericEditField
        L3L1VEditFieldLabel             matlab.ui.control.Label
        acs880L2L3VEditField            matlab.ui.control.NumericEditField
        L2L3VEditFieldLabel             matlab.ui.control.Label
        acs880L1L2VEditField            matlab.ui.control.NumericEditField
        L1L2VEditFieldLabel             matlab.ui.control.Label
        acs880ActivePowerEditField      matlab.ui.control.NumericEditField
        ActivePowerEditFieldLabel       matlab.ui.control.Label
        acs880PowerFactorEditField      matlab.ui.control.NumericEditField
        PowerFactorEditFieldLabel       matlab.ui.control.Label
        acs880FreqEditField             matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel_3       matlab.ui.control.Label
        AC800DriveTab                   matlab.ui.container.Tab
        GridLayoutACS880Power           matlab.ui.container.GridLayout
        acs800ResetLamp                 matlab.ui.control.Lamp
        ResetLampLabel_2                matlab.ui.control.Label
        acs800ExternalCtrlLocLamp       matlab.ui.control.Lamp
        ExternalCtrlLocLabel_2          matlab.ui.control.Label
        acs800RemoteCMDLamp             matlab.ui.control.Lamp
        RemoteCMDLampLabel_2            matlab.ui.control.Label
        acs800Inching2Lamp              matlab.ui.control.Lamp
        Inching2LampLabel_2             matlab.ui.control.Label
        acs800Inching1Lamp              matlab.ui.control.Lamp
        Inching1LampLabel_2             matlab.ui.control.Label
        acs800RampInZeroLamp            matlab.ui.control.Lamp
        RampInZeroLabel_2               matlab.ui.control.Label
        acs800RampHoldLamp              matlab.ui.control.Lamp
        RampHoldLampLabel_2             matlab.ui.control.Label
        acs800RampOutZeroLamp           matlab.ui.control.Lamp
        RampOutZeroLabel_2              matlab.ui.control.Label
        acs800EnableOperationLamp       matlab.ui.control.Lamp
        EnableOperationLampLabel_2      matlab.ui.control.Label
        acs800Off3Lamp                  matlab.ui.control.Lamp
        Off3LampLabel_2                 matlab.ui.control.Label
        acs800Off2Lamp                  matlab.ui.control.Lamp
        Off2LampLabel_2                 matlab.ui.control.Label
        acs800Off1Lamp                  matlab.ui.control.Lamp
        Off1LampLabel_2                 matlab.ui.control.Label
        DCBusVoltageLabel               matlab.ui.control.Label
        acs800dcBusVoltageEditField     matlab.ui.control.NumericEditField
        PowerEditFieldLabel_2           matlab.ui.control.Label
        acs800PowerEditField            matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel_2       matlab.ui.control.Label
        acs800FrequencyEditField        matlab.ui.control.NumericEditField
        TemperatureLabel                matlab.ui.control.Label
        acs800TemperatureEditField      matlab.ui.control.NumericEditField
        ACS800EK3443Tab                 matlab.ui.container.Tab
        GridLayoutACS800Power           matlab.ui.container.GridLayout
        acs800L3UEditField              matlab.ui.control.NumericEditField
        L3UEditFieldLabel_2             matlab.ui.control.Label
        acs800L3VEditField              matlab.ui.control.NumericEditField
        L3VEditFieldLabel_2             matlab.ui.control.Label
        acs800L2UEditField              matlab.ui.control.NumericEditField
        L2UEditField_2Label_2           matlab.ui.control.Label
        acs800L2VEditField              matlab.ui.control.NumericEditField
        L2VEditFieldLabel_2             matlab.ui.control.Label
        acs800L1UEditField              matlab.ui.control.NumericEditField
        L2UEditFieldLabel_2             matlab.ui.control.Label
        acs800L1VEditField              matlab.ui.control.NumericEditField
        L1VEditFieldLabel_2             matlab.ui.control.Label
        acs800L3L1VEditField            matlab.ui.control.NumericEditField
        L3L1VEditFieldLabel_2           matlab.ui.control.Label
        acs800L2L3VEditField            matlab.ui.control.NumericEditField
        L2L3VEditFieldLabel_2           matlab.ui.control.Label
        acs800L1L2VEditField            matlab.ui.control.NumericEditField
        L1L2VEditFieldLabel_2           matlab.ui.control.Label
        acs800ActivePowerEditField      matlab.ui.control.NumericEditField
        ActivePowerEditFieldLabel_2     matlab.ui.control.Label
        acs800PowerFactorEditField      matlab.ui.control.NumericEditField
        PowerFactorEditFieldLabel_2     matlab.ui.control.Label
        acs800FreqEditField             matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel_4       matlab.ui.control.Label
        HBMTab                          matlab.ui.container.Tab
        GridLayoutHBM                   matlab.ui.container.GridLayout
        hbmDriveOutPEditField           matlab.ui.control.NumericEditField
        DriveOutPEditFieldLabel         matlab.ui.control.Label
        hbmDriveOutP3EditField          matlab.ui.control.NumericEditField
        DriveOutP3EditFieldLabel        matlab.ui.control.Label
        hbmDriveOutI3EditField          matlab.ui.control.NumericEditField
        DriveOutI3EditFieldLabel        matlab.ui.control.Label
        hbmDriveOutU3EditField          matlab.ui.control.NumericEditField
        DriveOutU3EditFieldLabel        matlab.ui.control.Label
        hbmDriveOutP2EditField          matlab.ui.control.NumericEditField
        DriveOutP2EditFieldLabel        matlab.ui.control.Label
        hbmDriveOutI2EditField          matlab.ui.control.NumericEditField
        DriveOutI2EditFieldLabel        matlab.ui.control.Label
        hbmDriveOutU2EditField          matlab.ui.control.NumericEditField
        DriveOutU2EditFieldLabel        matlab.ui.control.Label
        hbmDriveOutP1EditField          matlab.ui.control.NumericEditField
        DriveOutP1EditFieldLabel        matlab.ui.control.Label
        hbmDriveOutI1EditField          matlab.ui.control.NumericEditField
        DriveOutI1EditFieldLabel        matlab.ui.control.Label
        hbmDriveOutU1EditField          matlab.ui.control.NumericEditField
        DriveOutU1EditFieldLabel        matlab.ui.control.Label
        hbmAcqStateEditField            matlab.ui.control.NumericEditField
        AcqStateEditFieldLabel          matlab.ui.control.Label
        hbmAcqTimeEditField             matlab.ui.control.NumericEditField
        AcqTimeEditFieldLabel           matlab.ui.control.Label
        hbmLatencyEditField             matlab.ui.control.NumericEditField
        LatencyEditFieldLabel           matlab.ui.control.Label
        DebugTab                        matlab.ui.container.Tab
        AbsLastCountsEditField          matlab.ui.control.NumericEditField
        AbsLastCountsEditFieldLabel     matlab.ui.control.Label
        AbsEncoderTurnsEditField        matlab.ui.control.NumericEditField
        AbsEncoderTurnsEditFieldLabel   matlab.ui.control.Label
        ACS880SpeedIGainSpinner         matlab.ui.control.Spinner
        ACS880SpeedIGainSpinnerLabel    matlab.ui.control.Label
        ACS880SpeedPGainSpinner         matlab.ui.control.Spinner
        ACS880SpeedPGainSpinnerLabel    matlab.ui.control.Label
        AbsEncoderPosEditField          matlab.ui.control.NumericEditField
        AbsEncoderPosEditFieldLabel     matlab.ui.control.Label
        AbsEncoderCountsEditField       matlab.ui.control.NumericEditField
        AbsEncoderCountsEditFieldLabel  matlab.ui.control.Label
        ExpTypeEditField                matlab.ui.control.EditField
        ExpTypeEditFieldLabel           matlab.ui.control.Label
        WindEmulatorAppOverviewPanel    matlab.ui.container.Panel
        EStopButton                     matlab.ui.control.Button
        TargetEditField                 matlab.ui.control.EditField
        TargetEditFieldLabel            matlab.ui.control.Label
        TimeEditField                   matlab.ui.control.NumericEditField
        TimeEditFieldLabel              matlab.ui.control.Label
        StartStopTargetButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        buildDir            % simulink build directory
        mdlName             % name of the built model [name]
        tg                  % the RT target
        tgName              % the RT target name
        
        hInstScalar         % instrument object for scalars
        hInstLine           % instrument object for lines
        decimation          % graphics update rate
        decimationReduced   % graphics updare rate for e.g. strings
     
        updateN         % keep track of update rate
        startTime       % keep track of update rate 
     
        ledOff = [0.80,0.80,0.80];
        ledGreenOn = [0.09, 0.77, 0];
        ledRedOn = [0.65, 0.06, 0.06];
        ledAmberOn = [0.93,0.69,0.13];
        
        waveAmp = 0;
        wavePeriod = 0;
        
        exW 
        exH
        
        includeHBM;
        
        runTime = 0;
        
        acs880SpeedPGain = 0;
        acs880SpeedIGain = 0;
        
        speedMin = -2000;
        speedMax = 2000;
        
        torqueMin = -90;
        torqueMax = 90;
        
        presMin = 0;
        presMax = 250;
        
        powerMin = -20;
        powerMax = 20;
        
        axesDt = 20;
        
        % static parameters
        startIcon = fullfile('icons','run_24.png')
        stopIcon = fullfile('icons','stop_24.png')
    end
    
    methods (Access = private)
        
        function setupInstrumentation(app)
           
            % creat an instrumentation object (not yet linked to the target)
            app.hInstScalar     = slrealtime.Instrument;
            app.hInstLine       = slrealtime.Instrument;
            
            % time
            app.hInstScalar.connectScalar(app.TimeEditField,[app.mdlName,'/fileAndUI/diagnostics/time_s'], 1,'Decimation',app.decimation,'Callback',@(t,d)double(app.timeUpdate(d)));
            
            %% ACS880
           
            % state related
            app.hInstScalar.connectScalar(app.acs880InternalStateTextArea,[app.mdlName,'/fileAndUI/acs880CtrlSignalsUI/state'],1,'Decimation',app.decimationReduced,'Callback',@(t,d)app.abbStateStr(d));
            app.hInstScalar.connectScalar(app.acs880StatusWordEditField,[app.mdlName,'/fileAndUI/acs880SignalsUI/statusWord'],1,'Decimation',app.decimationReduced,'Callback',@(t,d)app.abbStatusWordStr(d));
            app.hInstScalar.connectScalar(app.acs880CtrlWordEditField,[app.mdlName,'/fileAndUI/acs880CtrlSignalsUI/ctrlWord'],1,'Decimation',app.decimationReduced,'Callback',@(t,d)app.abbCtrlWordStr(d));
           
            % signals
            app.hInstScalar.connectScalar(app.acs880SpeedGauge,[app.mdlName,'/fileAndUI/acs880SignalsUI/motorSpeed_rpm'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs880SpeedEditField,[app.mdlName,'/fileAndUI/acs880SignalsUI/motorSpeed_rpm'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs880TorqueGauge,[app.mdlName,'/fileAndUI/acs880SignalsUI/motorTorque_Nm'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs880TorqueEditField,[app.mdlName,'/fileAndUI/acs880SignalsUI/motorTorque_Nm'], 1,'Decimation',app.decimation);
            
            % status word
            app.hInstScalar.connectScalar(app.acs880RdyOnLamp, [app.mdlName,'/fileAndUI/acs880SignalsUI/statusWordDetail/rdyOn'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs880RdyRunLamp, [app.mdlName,'/fileAndUI/acs880SignalsUI/statusWordDetail/rdyRun'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs880RemoteLamp, [app.mdlName,'/fileAndUI/acs880SignalsUI/statusWordDetail/remote'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs880TrippedLamp, [app.mdlName,'/fileAndUI/acs880SignalsUI/statusWordDetail/tripped'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffRedLED(d))
            app.hInstScalar.connectScalar(app.acs880InhibitedLamp, [app.mdlName,'/fileAndUI/acs880SignalsUI/statusWordDetail/switchOnInhibit'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffRedLED(d))
            app.hInstScalar.connectScalar(app.acs880AlarmLamp, [app.mdlName,'/fileAndUI/acs880SignalsUI/statusWordDetail/alarm'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffRedLED(d))
            
            % signal details
            app.hInstScalar.connectScalar(app.acs880MotorVoltageEditField,[app.mdlName,'/fileAndUI/acs880SignalsUI/motorVoltage_V'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs880MotorCurrentEditField,[app.mdlName,'/fileAndUI/acs880SignalsUI/motorCurrent_A'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs880FrequencyEditField,[app.mdlName,'/fileAndUI/acs880SignalsUI/frequency_Hz'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs880PowerEditField,[app.mdlName,'/fileAndUI/acs880SignalsUI/shaftPower_W'], 1,'Decimation',app.decimation);

            % ctrl word
            app.hInstScalar.connectScalar(app.acs880Off1Lamp, [app.mdlName,'/fileAndUI/acs880CtrlSignalsUI/ctrlWordDetail/off1Ctrl'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs880Off2Lamp, [app.mdlName,'/fileAndUI/acs880CtrlSignalsUI/ctrlWordDetail/off2Ctrl'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs880Off3Lamp, [app.mdlName,'/fileAndUI/acs880CtrlSignalsUI/ctrlWordDetail/off3Ctrl'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs880EnableOperationLamp, [app.mdlName,'/fileAndUI/acs880CtrlSignalsUI/ctrlWordDetail/enableOperation'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs880RampOutZeroLamp, [app.mdlName,'/fileAndUI/acs880CtrlSignalsUI/ctrlWordDetail/rampOutZero'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs880RampHoldLamp, [app.mdlName,'/fileAndUI/acs880CtrlSignalsUI/ctrlWordDetail/rampHold'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs880RampInZeroLamp, [app.mdlName,'/fileAndUI/acs880CtrlSignalsUI/ctrlWordDetail/rampInZero'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs880ResetLamp, [app.mdlName,'/fileAndUI/acs880CtrlSignalsUI/ctrlWordDetail/reset'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs880Inching1Lamp, [app.mdlName,'/fileAndUI/acs880CtrlSignalsUI/ctrlWordDetail/inching1'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs880Inching2Lamp, [app.mdlName,'/fileAndUI/acs880CtrlSignalsUI/ctrlWordDetail/inching2'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs880RemoteCMDLamp, [app.mdlName,'/fileAndUI/acs880CtrlSignalsUI/ctrlWordDetail/remoteCmd'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs880ExternalCtrlLocLamp, [app.mdlName,'/fileAndUI/acs880CtrlSignalsUI/ctrlWordDetail/extCtrlLoc'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            
            % ACS800
            
            % state related
            app.hInstScalar.connectScalar(app.acs800InternalStateTextArea,[app.mdlName,'/fileAndUI/acs800CtrlSignalsUI/state'],1,'Decimation',app.decimationReduced,'Callback',@(t,d)app.abbStateStr(d));
            app.hInstScalar.connectScalar(app.acs800StatusWordEditField,[app.mdlName,'/fileAndUI/acs800SignalsUI/statusWord'],1,'Decimation',app.decimationReduced,'Callback',@(t,d)app.abbStatusWordStr(d));
            app.hInstScalar.connectScalar(app.acs800CtrlWordEditField,[app.mdlName,'/fileAndUI/acs800CtrlSignalsUI/ctrlWord'],1,'Decimation',app.decimationReduced,'Callback',@(t,d)app.abbCtrlWordStr(d));
           
            % signals
            app.hInstScalar.connectScalar(app.acs800SpeedGauge,[app.mdlName,'/fileAndUI/acs800SignalsUI/motorSpeed_rpm'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800SpeedEditField,[app.mdlName,'/fileAndUI/acs800SignalsUI/motorSpeed_rpm'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800TorqueGauge,[app.mdlName,'/fileAndUI/acs800SignalsUI/motorTorque_Nm'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800TorqueEditField,[app.mdlName,'/fileAndUI/acs800SignalsUI/motorTorque_Nm'], 1,'Decimation',app.decimation);
            
            % status word
            app.hInstScalar.connectScalar(app.acs800RdyOnLamp, [app.mdlName,'/fileAndUI/acs800SignalsUI/statusWordDetail/rdyOn'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs800RdyRunLamp, [app.mdlName,'/fileAndUI/acs800SignalsUI/statusWordDetail/rdyRun'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs800RemoteLamp, [app.mdlName,'/fileAndUI/acs800SignalsUI/statusWordDetail/remote'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs800TrippedLamp, [app.mdlName,'/fileAndUI/acs800SignalsUI/statusWordDetail/tripped'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffRedLED(d))
            app.hInstScalar.connectScalar(app.acs800InhibitedLamp, [app.mdlName,'/fileAndUI/acs800SignalsUI/statusWordDetail/switchOnInhibit'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffRedLED(d))
            app.hInstScalar.connectScalar(app.acs800AlarmLamp, [app.mdlName,'/fileAndUI/acs800SignalsUI/statusWordDetail/alarm'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffRedLED(d))
            
             % signal details
            app.hInstScalar.connectScalar(app.acs800dcBusVoltageEditField,[app.mdlName,'/fileAndUI/acs800SignalsUI/dcBusVoltage_V'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800TemperatureEditField,[app.mdlName,'/fileAndUI/acs800SignalsUI/temperature'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800FrequencyEditField,[app.mdlName,'/fileAndUI/acs800SignalsUI/frequency_Hz'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800PowerEditField,[app.mdlName,'/fileAndUI/acs800SignalsUI/shaftPower_W'], 1,'Decimation',app.decimation);

            % ctrl word
            app.hInstScalar.connectScalar(app.acs800Off1Lamp, [app.mdlName,'/fileAndUI/acs800CtrlSignalsUI/ctrlWordDetail/off1Ctrl'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs800Off2Lamp, [app.mdlName,'/fileAndUI/acs800CtrlSignalsUI/ctrlWordDetail/off2Ctrl'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs800Off3Lamp, [app.mdlName,'/fileAndUI/acs800CtrlSignalsUI/ctrlWordDetail/off3Ctrl'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs800EnableOperationLamp, [app.mdlName,'/fileAndUI/acs800CtrlSignalsUI/ctrlWordDetail/enableOperation'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs800RampOutZeroLamp, [app.mdlName,'/fileAndUI/acs800CtrlSignalsUI/ctrlWordDetail/rampOutZero'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs800RampHoldLamp, [app.mdlName,'/fileAndUI/acs800CtrlSignalsUI/ctrlWordDetail/rampHold'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs800RampInZeroLamp, [app.mdlName,'/fileAndUI/acs800CtrlSignalsUI/ctrlWordDetail/rampInZero'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs800ResetLamp, [app.mdlName,'/fileAndUI/acs800CtrlSignalsUI/ctrlWordDetail/reset'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs800Inching1Lamp, [app.mdlName,'/fileAndUI/acs800CtrlSignalsUI/ctrlWordDetail/inching1'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs800Inching2Lamp, [app.mdlName,'/fileAndUI/acs800CtrlSignalsUI/ctrlWordDetail/inching2'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs800RemoteCMDLamp, [app.mdlName,'/fileAndUI/acs800CtrlSignalsUI/ctrlWordDetail/remoteCmd'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.acs800ExternalCtrlLocLamp, [app.mdlName,'/fileAndUI/acs800CtrlSignalsUI/ctrlWordDetail/extCtrlLoc'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            
            % experiment related
            app.hInstScalar.connectScalar(app.ExpTimeEditField,[app.mdlName,'/fileAndUI/expCtrlSignalsUI/time'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.RunCounterEditField,[app.mdlName,'/fileAndUI/expCtrlSignalsUI/runCounter'], 1,'Decimation',app.decimation,'Callback',@(t,d) double(d));
            app.hInstScalar.connectScalar(app.RampSlider,[app.mdlName,'/fileAndUI/expCtrlSignalsUI/ramp'], 1,'Decimation',app.decimation);
            
            app.hInstScalar.connectScalar(app.RunHILLamp, [app.mdlName,'/fileAndUI/expCtrlSignalsUI/runHil'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.ResetHILIntLamp, [app.mdlName,'/fileAndUI/expCtrlSignalsUI/resetHilIntegrator'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.RunSIDLamp, [app.mdlName,'/fileAndUI/expCtrlSignalsUI/runSid'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            
            app.hInstScalar.connectScalar(app.ResetSIDIntLamp, [app.mdlName,'/fileAndUI/expCtrlSignalsUI/resetSidIntegrator'],1,'Property','Color','Decimation',app.decimationReduced,'Callback',@(t,d)app.sig2OnOffGreenLED(d))
            app.hInstScalar.connectScalar(app.FexGauge,[app.mdlName,'/fileAndUI/hptoCtrlSignalsUI/excForce_N'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.FexEditField,[app.mdlName,'/fileAndUI/hptoCtrlSignalsUI/excForce_N'], 1,'Decimation',app.decimation);
                        
            app.hInstScalar.connectScalar(app.KDampingEditField,[app.mdlName,'/hptoSim/hptoModel/ControlAlgorithm/ForceToTorque/DesiredForce/kDampingNow'], 1,'Decimation',app.decimation,'Callback',@(t,d) d/1000); %TODO - this should come via UI/logging
            app.hInstScalar.connectScalar(app.KSpringEditField,[app.mdlName,'/hptoSim/hptoModel/ControlAlgorithm/ForceToTorque/DesiredForce/kSpringNow'], 1,'Decimation',app.decimation,'Callback',@(t,d) d/1000); %TODO - this should come via UI/logging
            app.hInstScalar.connectScalar(app.FexAmpEditField,[app.mdlName,'/processHptoInputs/excForceAmpNow_N'], 1,'Decimation',app.decimation,'Callback',@(t,d) d/1000); %TODO - this should come via UI/logging
            
            % from file related
            app.hInstScalar.connectScalar(app.FileNoSamplesEditField,[app.mdlName,'/sidCtrl/setpointGenerator/fromFile/fileSamples'], 1,'Decimation',app.decimation,'Callback',@(t,d) double(d));
            app.hInstScalar.connectScalar(app.FileIndexNowEditField,[app.mdlName,'/sidCtrl/setpointGenerator/fromFile/vecIndex'], 1,'Decimation',app.decimation,'Callback',@(t,d) double(d));
            app.hInstScalar.connectScalar(app.FileProgressEditField,[app.mdlName,'/sidCtrl/setpointGenerator/fromFile/vecPercent'], 1,'Decimation',app.decimation,'Callback',@(t,d) double(d));
            app.hInstScalar.connectScalar(app.FileCaseNowEditField,[app.mdlName,'/sidCtrl/setpointGenerator/fromFile/caseCounterSignalsNow'], 1,'Decimation',app.decimation,'Callback',@(t,d) double(d));
            app.hInstScalar.connectScalar(app.FileSpeedNowEditField,[app.mdlName,'/sidCtrl/setpointGenerator/fromFile/fromFileSpeedNow_rpm'], 1,'Decimation',app.decimation,'Callback',@(t,d) double(d));
            app.hInstScalar.connectScalar(app.FileTorqueNowEditField,[app.mdlName,'/sidCtrl/setpointGenerator/fromFile/fromFileTorqueNow_Nm'], 1,'Decimation',app.decimation,'Callback',@(t,d) double(d));
            
            % HPTO signals
            app.hInstScalar.connectScalar(app.PresbarSlider,[app.mdlName,'/fileAndUI/hptoSignalsUI/pressure_bar'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.CtrlSignal1Gauge,[app.mdlName,'/fileAndUI/hptoSignalsUI/ctrlSignal1'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.CtrlSignal2Gauge,[app.mdlName,'/fileAndUI/hptoSignalsUI/ctrlSignal2'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.genTorqueCmdNmGauge,[app.mdlName,'/fileAndUI/hptoSignalsUI/genTorqueCmd_Nm'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.HMOutputShaftTNmGauge,[app.mdlName,'/fileAndUI/hptoSignalsUI/hmOutputShaftTorque_Nm'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.HMOutputPumplmGauge,[app.mdlName,'/fileAndUI/hptoSignalsUI/genPumpFlow_lpm'], 1,'Decimation',app.decimation);
            
            % ABB control
            app.hInstScalar.connectScalar(app.acs880TorqueCmdGauge,[app.mdlName,'/fileAndUI/acs880CtrlSignalsUI/torqueSetpoint_Nm'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs880TorqueCmdEditField,[app.mdlName,'/fileAndUI/acs880CtrlSignalsUI/torqueSetpoint_Nm'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800TorqueCmdGauge,[app.mdlName,'/fileAndUI/acs800CtrlSignalsUI/torqueSetpoint_Nm'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800TorqueCmdEditField,[app.mdlName,'/fileAndUI/acs800CtrlSignalsUI/torqueSetpoint_Nm'], 1,'Decimation',app.decimation);
            
            % Power
            app.hInstScalar.connectScalar(app.HydrPowerkWGauge,[app.mdlName,'/fileAndUI/hptoSignalsUI/powerCalcs/hydrPower_W'], 1,'Decimation',app.decimation,'Callback',@(t,d) d/1000); % W->kW);
            app.hInstScalar.connectScalar(app.HydrAveragePowerkWGauge,[app.mdlName,'/fileAndUI/hptoSignalsUI/powerCalcs/hydrPowerAverage_W'], 1,'Decimation',app.decimation,'Callback',@(t,d) d/1000); % W->kW);
            app.hInstScalar.connectScalar(app.HydrAveragePowerkWEditField,[app.mdlName,'/fileAndUI/hptoSignalsUI/powerCalcs/hydrPowerAverage_W'], 1,'Decimation',app.decimation,'Callback',@(t,d) d/1000); % W->kW);
            
            app.hInstScalar.connectScalar(app.ExcSidePowerkWGauge,[app.mdlName,'/fileAndUI/hptoSignalsUI/powerCalcs/excShaftPower_W'], 1,'Decimation',app.decimation,'Callback',@(t,d) d/1000); % W->kW);
            app.hInstScalar.connectScalar(app.ExcSideAveragePowerkWGauge,[app.mdlName,'/fileAndUI/hptoSignalsUI/powerCalcs/excShaftPowerAverage_W'], 1,'Decimation',app.decimation,'Callback',@(t,d) d/1000); % W->kW);
            app.hInstScalar.connectScalar(app.ExcSideAveragePowerkWEditField,[app.mdlName,'/fileAndUI/hptoSignalsUI/powerCalcs/excShaftPowerAverage_W'], 1,'Decimation',app.decimation,'Callback',@(t,d) d/1000); % W->kW);
            
            app.hInstScalar.connectScalar(app.GenSidePowerkWGauge,[app.mdlName,'/fileAndUI/acs800SignalsUI/powerCals/shaftPower_W'], 1,'Decimation',app.decimation,'Callback',@(t,d) d/1000); % W->kW);
            app.hInstScalar.connectScalar(app.GenSideAveragePowerkWGauge,[app.mdlName,'/fileAndUI/acs800SignalsUI/powerCals/shaftPowerAverage_W'], 1,'Decimation',app.decimation,'Callback',@(t,d) d/1000); % W->kW);
            app.hInstScalar.connectScalar(app.GenSideAveragePowerkWEditField,[app.mdlName,'/fileAndUI/acs800SignalsUI/powerCals/shaftPowerAverage_W'], 1,'Decimation',app.decimation,'Callback',@(t,d) d/1000); % W->kW);
            
            %Shaft Signals
            app.hInstScalar.connectScalar(app.ShaftTorqueEditField,[app.mdlName,'/fileAndUI/shaftSignalsUI/torqueActual_Nm'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.ShaftTorqueGauge,[app.mdlName,'/fileAndUI/shaftSignalsUI/torqueActual_Nm'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.ShaftSpeedGauge,[app.mdlName,'/fileAndUI/shaftSignalsUI/absEncoderSpeed_rpm'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.ShaftSpeedEditField,[app.mdlName,'/fileAndUI/shaftSignalsUI/absEncoderSpeed_rpm'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.AbsEncoderCountsEditField,[app.mdlName,'/fileAndUI/shaftSignalsUI/absEncoderCounts'], 1,'Decimation',app.decimation,'Callback',@(t,d) double(d));
            app.hInstScalar.connectScalar(app.AbsLastCountsEditField,[app.mdlName,'/readShaftSignals/countsToRads/lastRawCounts'], 1,'Decimation',app.decimation,'Callback',@(t,d) double(d));
            app.hInstScalar.connectScalar(app.AbsEncoderTurnsEditField,[app.mdlName,'/fileAndUI/shaftSignalsUI/absEncoderTurns'], 1,'Decimation',app.decimation,'Callback',@(t,d) double(d));
            app.hInstScalar.connectScalar(app.AbsEncoderPosEditField,[app.mdlName,'/fileAndUI/shaftSignalsUI/absEncoderPosition_rad'], 1,'Decimation',app.decimation);

            % ACS880 EK3443 power signals
            app.hInstScalar.connectScalar(app.acs880FreqEditField,[app.mdlName,'/readAcs880Power/totalFrequency'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs880PowerFactorEditField,[app.mdlName,'/readAcs880Power/totalPowFactor'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs880ActivePowerEditField,[app.mdlName,'/readAcs880Power/totalActivePow'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs880L1L2VEditField,[app.mdlName,'/readAcs880Power/L1L2Voltage'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs880L2L3VEditField,[app.mdlName,'/readAcs880Power/L2L3Voltage'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs880L3L1VEditField,[app.mdlName,'/readAcs880Power/L3L1Voltage'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs880L1VEditField,[app.mdlName,'/readAcs880Power/L1Voltage'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs880L1UEditField,[app.mdlName,'/readAcs880Power/L1Current'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs880L2VEditField,[app.mdlName,'/readAcs880Power/L2Voltage'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs880L2UEditField,[app.mdlName,'/readAcs880Power/L2Current'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs880L3VEditField,[app.mdlName,'/readAcs880Power/L3Voltage'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs880L3UEditField,[app.mdlName,'/readAcs880Power/L3Current'], 1,'Decimation',app.decimation);
            
            % ACS800 EK3443 power signals
            app.hInstScalar.connectScalar(app.acs800FreqEditField,[app.mdlName,'/readAcs800Power/totalFrequency'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800PowerFactorEditField,[app.mdlName,'/readAcs800Power/totalPowFactor'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800ActivePowerEditField,[app.mdlName,'/readAcs800Power/totalActivePow'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800L1L2VEditField,[app.mdlName,'/readAcs800Power/L1L2Voltage'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800L2L3VEditField,[app.mdlName,'/readAcs800Power/L2L3Voltage'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800L3L1VEditField,[app.mdlName,'/readAcs800Power/L3L1Voltage'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800L1VEditField,[app.mdlName,'/readAcs800Power/L1Voltage'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800L1UEditField,[app.mdlName,'/readAcs800Power/L1Current'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800L2VEditField,[app.mdlName,'/readAcs800Power/L2Voltage'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800L2UEditField,[app.mdlName,'/readAcs800Power/L2Current'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800L3VEditField,[app.mdlName,'/readAcs800Power/L3Voltage'], 1,'Decimation',app.decimation);
            app.hInstScalar.connectScalar(app.acs800L3UEditField,[app.mdlName,'/readAcs800Power/L3Current'], 1,'Decimation',app.decimation);
         
            % HBM power signals
            if app.includeHBM 
                app.hInstScalar.connectScalar(app.hbmLatencyEditField,[app.mdlName,'/readPowerHbm/latency'], 1,'Decimation',app.decimation);
                app.hInstScalar.connectScalar(app.hbmAcqTimeEditField,[app.mdlName,'/readPowerHbm/acqTime'], 1,'Decimation',app.decimation);
                app.hInstScalar.connectScalar(app.hbmAcqStateEditField,[app.mdlName,'/readPowerHbm/acqState'], 1,'Decimation',app.decimation);
                app.hInstScalar.connectScalar(app.hbmDriveOutU1EditField,[app.mdlName,'/readPowerHbm/driveOutU1RMS'], 1,'Decimation',app.decimation);
                app.hInstScalar.connectScalar(app.hbmDriveOutU2EditField,[app.mdlName,'/readPowerHbm/driveOutU2RMS'], 1,'Decimation',app.decimation);
                app.hInstScalar.connectScalar(app.hbmDriveOutU3EditField,[app.mdlName,'/readPowerHbm/driveOutU3RMS'], 1,'Decimation',app.decimation);
                app.hInstScalar.connectScalar(app.hbmDriveOutI1EditField,[app.mdlName,'/readPowerHbm/driveOutI1RMS'], 1,'Decimation',app.decimation);
                app.hInstScalar.connectScalar(app.hbmDriveOutI2EditField,[app.mdlName,'/readPowerHbm/driveOutI2RMS'], 1,'Decimation',app.decimation);
                app.hInstScalar.connectScalar(app.hbmDriveOutI3EditField,[app.mdlName,'/readPowerHbm/driveOutI3RMS'], 1,'Decimation',app.decimation);
                app.hInstScalar.connectScalar(app.hbmDriveOutP1EditField,[app.mdlName,'/readPowerHbm/driveOutP1'], 1,'Decimation',app.decimation);
                app.hInstScalar.connectScalar(app.hbmDriveOutP2EditField,[app.mdlName,'/readPowerHbm/driveOutP2'], 1,'Decimation',app.decimation);
                app.hInstScalar.connectScalar(app.hbmDriveOutP3EditField,[app.mdlName,'/readPowerHbm/driveOutP3'], 1,'Decimation',app.decimation);
                app.hInstScalar.connectScalar(app.hbmDriveOutPEditField,[app.mdlName,'/readPowerHbm/driveOutP'], 1,'Decimation',app.decimation);
            end
            
            % UI axes
            app.hInstLine.connectLine(app.UIAxesSpeed,[app.mdlName,'/fileAndUI/hptoSignalsUI/excShaftSpeed_rpm'],1,'Decimation',app.decimation);
            app.hInstLine.connectLine(app.UIAxesSpeed,[app.mdlName,'/fileAndUI/acs800SignalsUI/motorSpeed_rpm'],1,'Decimation',app.decimation);
            app.hInstLine.connectLine(app.UIAxesTorque,[app.mdlName,'/fileAndUI/hptoSignalsUI/genTorqueCmd_Nm'],1,'Decimation',app.decimation);
            app.hInstLine.connectLine(app.UIAxesTorque,[app.mdlName,'/fileAndUI/hptoSignalsUI/hmOutputShaftTorque_Nm'],1,'Decimation',app.decimation);
            app.hInstLine.connectLine(app.UIAxesPressure,[app.mdlName,'/fileAndUI/hptoSignalsUI/pressure_bar'],1,'Decimation',app.decimation);
            app.hInstLine.connectLine(app.UIAxesSwashPlate,[app.mdlName,'/fileAndUI/hptoSignalsUI/ctrlSignal1'],1,'Decimation',app.decimation);
            app.hInstLine.connectLine(app.UIAxesSwashPlate,[app.mdlName,'/fileAndUI/hptoSignalsUI/ctrlSignal2'],1,'Decimation',app.decimation);
            
            app.hInstLine.connectLine(app.UIAxesPower,[app.mdlName,'/fileAndUI/hptoSignalsUI/powerCalcs/excShaftPower_W'],1,'Decimation',app.decimation,'Callback',@(t,d) d/1000); % W->kW);
            app.hInstLine.connectLine(app.UIAxesPower,[app.mdlName,'/fileAndUI/hptoSignalsUI/powerCalcs/hydrPower_W'],1,'Decimation',app.decimation,'Callback',@(t,d) d/1000); % W->kW);
            app.hInstLine.connectLine(app.UIAxesPower,[app.mdlName,'/fileAndUI/acs800SignalsUI/powerCals/shaftPower_W'],1,'Decimation',app.decimation,'Callback',@(t,d) d/1000); % W->kW);
            
            
            % a general callback to manage the UI axes time range
            connectCallback(app.hInstLine,@app.regularCallback);
            
            % add the instrument object to the app
            app.tg.addInstrument(app.hInstScalar);
            app.tg.addInstrument(app.hInstLine);
            
            % add the legends last
            legend(app.UIAxesSpeed,'Exc. Side (SIM)','Gen. Side (HIL)')
            legend(app.UIAxesTorque,'Gen Cmd (HIL)','HM Output Shaft (SIM)')
            legend(app.UIAxesSwashPlate, 'Exc Side (SIM)','Gen Side (SIM)')
            legend(app.UIAxesPressure, 'System (SIM)')
            legend(app.UIAxesPower, 'Exc. Side (SIM)','Hydraulic (SIM)','Gen. Side (HIL)')
            
        end
        
        function timeNow = timeUpdate(app,data)
    
            if sum(size(data) >= 1) % could be more than one
                timeNow = data(1);
            else
                timeNow = 0; % this should never occur during a callback
            end

            %if app.updateN == 0
            %    app.startTime = timeNow;
            %end

            %app.updateN = app.updateN + 1;

            %app.updateRateEditField.Value = double((timeNow - app.startTime)/app.updateN * 1000.0); % in (ms)

        end
        
        function regularCallback(app,~,eventData)

            %% update the UI axes time range
            timeNow = eventData.ExecTime;
            if max(timeNow) >= max(app.UIAxesSpeed.XLim)
          
                % clear line data
                app.hInstLine.clearScalarAndLineData();
                
                lims = floor(max(timeNow)/app.axesDt)*app.axesDt + [0 app.axesDt];
                
                % adjust the xlim
                app.UIAxesSpeed.XLim = lims;
                app.UIAxesTorque.XLim = lims;
                app.UIAxesPressure.XLim = lims;
                app.UIAxesSwashPlate.XLim = lims;
                app.UIAxesPower.XLim = lims;
                
                % set the ylim for all UI axes
                app.axesYlim();
            end
            
            % calculate the power conversion factors
%             powerExc = app.AverageExcShaftPowerEditField.Value;
%             powerHydr = app.AverageHydraulicPowerEditField.Value;
%             powerGen = app.AverageGenShaftPowerEditField.Value;
%             
%             etaExc2Hyraulic = 0;
%             if abs(powerExc) > 0.05 % avoid division by small numbers
%                 etaExc2Hyraulic = powerHydr/powerExc*100; % in %
%             end
%             
%             etaExc2Gen = 0;
%             if abs(powerExc) > 0.05
%                 etaExc2Gen = powerGen/powerExc*100;
%             end
%             
%             app.ExcitationHydraulicEditField.Value = etaExc2Hyraulic;
%             app.ExcitationGeneratorEditField.Value = etaExc2Gen;
            
            
        end % regular callback
        
        
        function str = abbStateStr(~, data)
            
            str = 'NAN';
            if sum(size(data) >= 1) % could be more than one
                stateNow = int32(data(1));
                stateNum = num2str(stateNow);
                
                switch stateNow
                    case abbStateEnum.undefined
                        str = ['Undefined (', stateNum, ')'];
                    case abbStateEnum.init
                        str = ['Init (', stateNum, ')'];
                    case abbStateEnum.notReadyToSwitchOn
                        str = ['Not ready to switch on (', stateNum, '). Waiting for drive ready flag / no warnings / not inhibited.'];
                    case abbStateEnum.readyToSwitchOn
                        str = ['Ready to switch on (', stateNum, '). Press power up to make drive ready.'];
                    case abbStateEnum.operationDisabled
                        str = ['Drive disabled & ready (', stateNum, '). Press power up to to enable drive.'];
                    case abbStateEnum.operationEnabled
                        str = ['Operation enabled (', stateNum, '). Ready to run case.'];
                    case abbStateEnum.delayOff1
                        str = ['Delay Off1 (', stateNum, '). Awaiting timeout ...'];
                    otherwise 
                        str = ['Status TBC (', stateNum, ')'];
                end
            end
        end
        
        function str = abbStatusWordStr(~, data)
            
            str = 'NAN';
            if sum(size(data) >= 1) % could be more than one
                statusNow = int16(data(1));
                statusNum = num2str(statusNow);
                
                switch statusNow
                    case abbStatusWordEnum.undefined
                        str = ['Undefined (', statusNum, ')'];
                    otherwise 
                        str = ['TBC (', statusNum, ')'];
                end
            end
        end
        
        function str = abbCtrlWordStr(~, data)
            
            str = 'NAN';
            if sum(size(data) >= 1) % could be more than one
                ctrlNow = uint16(data(1));
                ctrlNum = num2str(ctrlNow);
                
                switch ctrlNow
                    case abbCtrlWordEnum.undefined
                        str = ['Undefined (', ctrlNum, ')'];
                    otherwise 
                        str = ['Ctrl TBC (', ctrlNum, ')'];
                end
            end
        end
        
        function led = sig2OnOffGreenLED(app,data)
            if data>=1
                led = app.ledGreenOn;
            else
                led = app.ledOff;
            end
        end

        function led = sig2OnOffAmberLED(app,data)
            if data>=1
                led = app.ledAmberOn;
            else
                led = app.ledOff;
            end
        end

        function led = sig2OnOffRedLED(app,data)
            if data>=1
                led = app.ledRedOn;
            else
                led = app.ledOff;
            end
        end
        
        function updateExcProps(app)
            
            % Step 1: Recalculate the excitation force
            A = app.waveAmp;
            T = app.wavePeriod;
            
            Fex = A*interp1(app.exW, app.exH, (1/T)*2*pi);

            % Step 2: Recalculate the optimum aborption controller settings
            optData = OptimizationResultsLoad(T,A); 

            kDamping = optData.Kdamping;
            kSpring  = optData.Kpos;
            
            fprintf('Updating excitation and controller properties as follows:\n');
            fprintf('A          = %4.2f m\n',A);
            fprintf('T          = %4.2f s\n',T);
            fprintf('Fex        = %4.2f N\n',Fex);
            fprintf('kDamping   = %4.2f N/(m/s)\n',kDamping);
            fprintf('kSpring    = %4.2f N/m\n',kSpring);
            
            % Step 3: Update the values in SLRT
            app.tg.setparam([app.mdlName,'/processHptoInputs/excForceAmp_N'],'Value',Fex)
            app.tg.setparam([app.mdlName,'/processHptoInputs/excForceFreq_Hz'],'Value',1/T)
            
            app.tg.setparam([app.mdlName,'/hptoSim/hptoModel/ControlAlgorithm/ForceToTorque/DesiredForce/kDamping'],'Value',kDamping)
            app.tg.setparam([app.mdlName,'/hptoSim/hptoModel/ControlAlgorithm/ForceToTorque/DesiredForce/kSpring'],'Value',kSpring)
           
        end
        
        function axesYlim(app)
%             %checks to avoid errors at run time
%             
%             if app.excShaftSpeedMin >= app.excShaftSpeedMax 
%                 app.excShaftSpeedMin = app.excShaftSpeedMax-1;
%             end
%             
%             if app.genShaftSpeedMin >= app.genShaftSpeedMax 
%                 app.genShaftSpeedMin = app.genShaftSpeedMax-1;
%             end
%             
%             if app.presMin >= app.presMax
%                 app.presMin = app.presMax-1;
%             end
%             
%             if app.powerMin >= app.powerMax
%                 app.powerMin = app.powerMax-1;
%             end
            
            app.UIAxesSpeed.YLim = [app.speedMin app.speedMax];
            app.UIAxesTorque.YLim = [app.torqueMin app.torqueMax];
            app.UIAxesPressure.YLim = [app.presMin app.presMax];
            app.UIAxesSwashPlate.YLim = [-1 1];
            app.UIAxesPower.YLim = [app.powerMin, app.powerMax];
           
        end
        
        function updateAcs880PICtrl(app)
            app.tg.setparam([app.mdlName,'/sidCtrl/acs880SpeedPGain'],'Value',app.acs880SpeedPGain)
            app.tg.setparam([app.mdlName,'/sidCtrl/acs880SpeedIGain'],'Value',app.acs880SpeedIGain)
        end
        
        function updateRunTime(app)
            app.tg.setparam([app.mdlName,'/expCtrl/expRunTime'],'Value',app.runTime);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            d = uiprogressdlg(app.WindEmulatorFigure,'Title','Please Wait','Message','Running the startup function ...');

            d.Value = 0.1;
            d.Message = 'Setting up axes';
            
            % TODO - revisit these settings (were introduced when app was
            % lagging, but removing SDI logging may have improved this)
            app.UIAxesSpeed.NextPlot = 'replaceall';
            app.UIAxesTorque.NextPlot = 'replaceall';
            app.UIAxesPressure.NextPlot = 'replaceall';
            app.UIAxesSwashPlate.NextPlot = 'replaceall';
            app.UIAxesPower.NextPlot = 'replaceall';
            
            app.UIAxesSpeed.Toolbar.Visible = 'off'; 
            app.UIAxesTorque.Toolbar.Visible = 'off'; 
            app.UIAxesPressure.Toolbar.Visible = 'off'; 
            app.UIAxesSwashPlate.Toolbar.Visible = 'off'; 
            app.UIAxesPower.Toolbar.Visible = 'off'; 
            
            disableDefaultInteractivity(app.UIAxesSpeed)
            disableDefaultInteractivity(app.UIAxesTorque)
            disableDefaultInteractivity(app.UIAxesPressure)
            disableDefaultInteractivity(app.UIAxesSwashPlate)
            disableDefaultInteractivity(app.UIAxesPower)
                        
            %% get model information
            d.Value = 0.2;
            d.Message = 'Loading workspace data';

            app.mdlName = evalin('base','mdlName');
            app.buildDir = evalin('base','buildDir');
            app.tgName = evalin('base','tgName');
            app.decimation = evalin('base','appDecimation');
            app.decimationReduced = evalin('base','appDecimationReduced');
            app.waveAmp = evalin('base','waveAmpInit');
            app.wavePeriod = evalin('base','wavePeriodInit');
            app.exW = evalin('base','wex');    % excitation w (rad/s)
            app.exH = evalin('base','Hex');    % exciation force (N/m)   
            app.acs880SpeedPGain = evalin('base','acs880SpeedPGainInit');
            app.acs880SpeedIGain = evalin('base','acs880SpeedIGainInit');
            app.runTime = evalin('base','runTimeInit');
            app.includeHBM = evalin('base','includeHBM');
            
            %% connect the RT and instrument objects
            d.Value = 0.4;
            d.Message = ['Connecting to the RT target',app.tgName, ' ...'];

            app.tg = slrealtime(app.tgName);
            app.tg.stop;

            %% load the model on to the target
            d.Value = 0.5;
            d.Message = ['Loading the model ',app.mdlName,' ...'];
            app.tg.load(fullfile(app.buildDir,app.mdlName));

            % make sure stimulation is off
            stop(app.tg.Stimulation,'all');

            % Make sure file logging is disabled initially. Without this,
            % the RT application will briefly log a few samples during
            % model load
            %app.tg.FileLog.disable;

            %% remove any instrument pannels from previous sessions
            app.tg.removeAllInstruments()

            app.StartStopTargetButton.Icon = app.startIcon;
            app.StartStopTargetButton.Text = 'Start Speedgoat';

            %% setup the instrumentation object
            d.Value = 0.6;
            d.Message = 'Setting up instrumentation pannel ...';

            app.setupInstrumentation;
            app.TargetEditField.Value = app.tg.status;

            % update the app state
            d.Value = 0.9;
            d.Message = ['Updating the app ',app.mdlName,' ...'];
            %app.updateN = double(0.0);
            %app.startTime = double(0.0);
                
            % TODO - revisit the below axes calls
            app.UIAxesSpeed.Interruptible  = 'off';
            app.UIAxesSpeed.BusyAction = 'cancel';
            
            app.UIAxesTorque.Interruptible = 'off';
            app.UIAxesTorque.BusyAction = 'cancel';
            
            app.UIAxesPressure.Interruptible = 'off';
            app.UIAxesPressure.BusyAction = 'cancel';
            
            app.UIAxesSwashPlate.Interruptible = 'off';
            app.UIAxesSwashPlate.BusyAction = 'cancel';
            
            app.UIAxesPower.Interruptible = 'off';
            app.UIAxesPower.BusyAction = 'cancel';
            
            app.axesYlim()
                
            %% close progress bar
            d.close();
        end

        % Button pushed function: StartStopTargetButton
        function StartStopTargetButtonPushed(app, event)
            if strcmp(app.StartStopTargetButton.Icon, app.startIcon)

                d = uiprogressdlg(app.WindEmulatorFigure,'Title','Please Wait','Message','Starting target ...');
            
                % START
                d.Value = 0.1;
                d.Message = ['Loading the model ',app.mdlName,' ...'];
                app.tg.load(fullfile(app.buildDir,app.mdlName));
            
                d.Value = 0.2;
                d.Message = ['Starting the model ',app.mdlName,' ...'];
                app.tg.start('AutoImportFileLog',false,'ExportToBaseWorkspace',false,'FileLogMaxRuns',100);
            
                % update the app state
                d.Value = 0.5;
                d.Message = 'Updating app ...';
                app.StartStopTargetButton.Icon = app.stopIcon;  
                app.StartStopTargetButton.Text = 'Stop Speedgoat';  
                
                % the the init wave settings in SLRT and the UI
                app.updateExcProps();
                app.WaveAmpSpinner.Value = app.waveAmp;
                app.WavePeriodSpinner.Value = app.wavePeriod;
                
                % set the PI init settings in SLRT and the UI
                app.updateAcs880PICtrl();
                app.ACS880SpeedPGainSpinner.Value = app.acs880SpeedPGain;
                app.ACS880SpeedIGainSpinner.Value = app.acs880SpeedIGain;
            
                % set the init run time in SLRT and the UI
                app.updateRunTime();
                app.RunTimeEditField.Value = app.runTime;
                
                % stop stimumation
                d.Value = 0.7;
                d.Message = 'Stop inport stimulation ...';
                stop(app.tg.Stimulation,'all');
                
% 
%                 app.TargetEditField.Value = app.tg.status;
%                 %app.appRunning(true);
% 
%                 app.updateN = double(0.0);
%                 app.startTime = double(0.0);
%                 
%                 % clear old scope data
%                 app.hInstLine.clearScalarAndLineData();
%   
%                  % set initial axes limits
%                 app.InputShaftSpeedUIAxes.XLim = [0 app.axesDt];
%                 app.OutputShaftSpeedUIAxes.XLim = [0 app.axesDt];
%                 app.PresUIAxes.XLim = [0 app.axesDt];
%                 app.SwashPlateUIAxes.XLim = [0 app.axesDt];
%                 app.PowerUIAxes.XLim = [0 app.axesDt];
%                 
%                 app.excMinEditField.Value = app.excShaftSpeedMin;
%                 app.excMaxEditField.Value = app.excShaftSpeedMax;
%                 
%                 app.genMinEditField.Value = app.genShaftSpeedMin;
%                 app.genMaxEditField.Value = app.genShaftSpeedMax;
%                 
%                 app.powerMinEditField.Value = app.powerMin;
%                 app.powerMaxEditField.Value = app.powerMax;
%                 
%                 app.presMinEditField.Value = app.presMin;
%                 app.presMaxEditField.Value = app.presMax;
%                 
%                 app.axesYlim()
%                 app.updateWaveProps();
%                 
%                 % set the initial PTO damping
%                 app.DampingSlider.Value = app.ptoDamping;
%                 app.tg.setparam([app.mdlName,'/ctrl/ptoCtrl/ptoDamping'],'Value',app.ptoDamping)


                d.close();

            else
                % STOP
                d = uiprogressdlg(app.WindEmulatorFigure,'Title','Please Wait','Message','Stopping target ...');

                % stop stimumation
                d.Value = 0.2;
                d.Message = 'Stop inport stimulation ...';
                stop(app.tg.Stimulation,'all');
                
                d.Value = 0.4;
                d.Message = 'Stopping target ...';
                app.tg.stop;

                % pdate the app internal state
                d.Value = 0.6;
                d.Message = 'Updating app ...';
                app.StartStopTargetButton.Icon = app.startIcon;
                app.StartStopTargetButton.Text = 'Start Speedgoat';

                %app.TargetEditField.Value = app.tg.status;
                %app.appRunning(false)

                %app.updateN = double(0.0);
                %app.startTime = double(0.0);
                
                d.close()
            end
        end

        % Button pushed function: acs880PowerUp
        function acs880PowerUpButtonPushed(app, event)
            val = (app.tg.getparam([app.mdlName,'/acs880Ctrl/powerUpButton'],'Value'));
            app.tg.setparam([app.mdlName,'/acs880Ctrl/powerUpButton'],'Value',~val)
        end

        % Button pushed function: acs880PowerDown
        function acs880PowerDownButtonPushed(app, event)
            val = (app.tg.getparam([app.mdlName,'/acs880Ctrl/powerDownButton'],'Value'));
            app.tg.setparam([app.mdlName,'/acs880Ctrl/powerDownButton'],'Value',~val) 
        end

        % Button pushed function: acs800PowerUp
        function acs800PowerUpButtonPushed(app, event)
            val = (app.tg.getparam([app.mdlName,'/acs800Ctrl/powerUpButton'],'Value'));
            app.tg.setparam([app.mdlName,'/acs800Ctrl/powerUpButton'],'Value',~val) 
        end

        % Button pushed function: acs800PowerDown
        function acs800PowerDownButtonPushed(app, event)
            val = (app.tg.getparam([app.mdlName,'/acs800Ctrl/powerDownButton'],'Value'));
            app.tg.setparam([app.mdlName,'/acs800Ctrl/powerDownButton'],'Value',~val) 
        end

        % Button pushed function: EStopButton
        function EStopButtonPushed(app, event)
            val = (app.tg.getparam([app.mdlName,'/expCtrl/eStopButton'],'Value'));
            app.tg.setparam([app.mdlName,'/expCtrl/eStopButton'],'Value',~val) 
        end

        % Value changed function: SpeedRefSpinner
        function SpeedRefSpinnerValueChanged(app, event)
            value = app.SpeedRefSpinner.Value;
            app.tg.setparam([app.mdlName,'/processHptoInputs/speedReference'],'Value',value)
        end

        % Value changed function: WaveAmpSpinner
        function WaveAmpSpinnerValueChanged(app, event)
            app.waveAmp = app.WaveAmpSpinner.Value;
            app.updateExcProps();
        end

        % Value changed function: WavePeriodSpinner
        function WavePeriodSpinnerValueChanged(app, event)
            app.wavePeriod = app.WavePeriodSpinner.Value;
            app.updateExcProps();
        end

        % Selection changed function: ExperimentTypeButtonGroup
        function ExperimentTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ExperimentTypeButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'Off')
                app.tg.setparam([app.mdlName,'/expCtrl/expType'],'Value',expTypeEnum.off)
            elseif strcmp(selectedButton.Text,'SID')
                app.tg.setparam([app.mdlName,'/expCtrl/expType'],'Value',expTypeEnum.sid)
            elseif strcmp(selectedButton.Text,'HIL')
                app.tg.setparam([app.mdlName,'/expCtrl/expType'],'Value',expTypeEnum.hil)
            else
                app.tg.setparam([app.mdlName,'/expCtrl/expType'],'Value',expTypeEnum.off)
            end
            
            
        end

        % Selection changed function: SIDTypeButtonGroup
        function SIDTypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.SIDTypeButtonGroup.SelectedObject;
            
            if strcmp(selectedButton.Text,'Off')
                app.tg.setparam([app.mdlName,'/sidCtrl/setpointGenerator/sidType'],'Value',sidTypeEnum.off)
            elseif strcmp(selectedButton.Text,'Manual')
                app.tg.setparam([app.mdlName,'/sidCtrl/setpointGenerator/sidType'],'Value',sidTypeEnum.manual)
            elseif strcmp(selectedButton.Text,'FromFile')
                app.tg.setparam([app.mdlName,'/sidCtrl/setpointGenerator/sidType'],'Value',sidTypeEnum.fromFile)
            else
                app.tg.setparam([app.mdlName,'/sidCtrl/setpointGenerator/sidType'],'Value',sidTypeEnum.off)
            end
            
        end

        % Value changed function: SpeedSpinner
        function SpeedSpinnerValueChanged(app, event)
            value = app.SpeedSpinner.Value;
            app.tg.setparam([app.mdlName,'/sidCtrl/setpointGenerator/manualSpeedSetpoint_rpm'],'Value',value)
        end

        % Value changed function: TorqueSpinner
        function TorqueSpinnerValueChanged(app, event)
            value = app.TorqueSpinner.Value;
            app.tg.setparam([app.mdlName,'/sidCtrl/setpointGenerator/manualTorqueSetpoint_Nm'],'Value',value)
        end

        % Button pushed function: StartRunButton
        function StartRunButtonPushed(app, event)
            value = (app.tg.getparam([app.mdlName,'/expCtrl/startButton'],'Value'));
            
            stop(app.tg.Stimulation,'all'); % stop to make sure we return to the beginning of the signal
            start(app.tg.Stimulation,'all'); % start the signal stimulation
                
            app.tg.setparam([app.mdlName,'/expCtrl/startButton'],'Value',~value);
        end

        % Button pushed function: StopRunButton
        function StopRunButtonPushed(app, event)
            value = (app.tg.getparam([app.mdlName,'/expCtrl/stopButton'],'Value'));
            app.tg.setparam([app.mdlName,'/expCtrl/stopButton'],'Value',~value);
        end

        % Value changed function: RunTimeEditField
        function RunTimeEditFieldValueChanged(app, event)
            app.runTime = app.RunTimeEditField.Value;
            app.updateRunTime();
        end

        % Value changed function: ACS880SpeedPGainSpinner
        function ACS880SpeedPGainSpinnerValueChanged(app, event)
            app.acs880SpeedPGain = app.ACS880SpeedPGainSpinner.Value;
            app.updateAcs880PICtrl();
        end

        % Value changed function: ACS880SpeedIGainSpinner
        function ACS880SpeedIGainSpinnerValueChanged(app, event)
            app.acs880SpeedIGain = app.ACS880SpeedIGainSpinner.Value;
            app.updateAcs880PICtrl();
        end

        % Value changed function: MinSpeedRefSpinner
        function MinSpeedRefSpinnerValueChanged(app, event)
            value = app.MinSpeedRefSpinner.Value;
            app.tg.setparam([app.mdlName,'/hptoSim/hptoModel/ControlAlgorithm/PressureBelowPmin/SpeedControl1/shaftSpeedRefMin'],'Value',value);
            app.tg.setparam([app.mdlName,'/hptoSim/hptoModel/ControlAlgorithm/PressureAbovePmin/SpeedControl1/shaftSpeedRefMin'],'Value',value);      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WindEmulatorFigure = uifigure('Visible', 'off');
            app.WindEmulatorFigure.Name = 'MATLAB App';
            app.WindEmulatorAppOverviewPanel = uipanel(app.WindEmulatorFigure);
            app.WindEmulatorAppOverviewPanel.Title = 'Wind Emulator App Overview';
            app.StartStopTargetButton = uibutton(app.WindEmulatorAppOverviewPanel, 'push');
            app.StartStopTargetButton.ButtonPushedFcn = createCallbackFcn(app, @StartStopTargetButtonPushed, true);
            app.StartStopTargetButton.Text = 'StartStopButton';
            app.TimeEditFieldLabel = uilabel(app.WindEmulatorAppOverviewPanel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.WindEmulatorAppOverviewPanel, 'numeric');
            app.TimeEditField.ValueDisplayFormat = '%.2f s';
            app.TargetEditFieldLabel = uilabel(app.WindEmulatorAppOverviewPanel);
            app.TargetEditFieldLabel.Text = 'Target';
            app.TargetEditField = uieditfield(app.WindEmulatorAppOverviewPanel, 'text');
            app.EStopButton = uibutton(app.WindEmulatorAppOverviewPanel, 'push');
            app.EStopButton.ButtonPushedFcn = createCallbackFcn(app, @EStopButtonPushed, true);
            app.EStopButton.Text = 'E-Stop';
            app.TabGroup = uitabgroup(app.WindEmulatorFigure);
            app.ScopesTab = uitab(app.TabGroup);
            app.ScopesTab.Title = 'Scopes';
            app.UIAxesSpeed = uiaxes(app.ScopesTab);
            title(app.UIAxesSpeed, 'Speed')
            xlabel(app.UIAxesSpeed, 'time (s)')
            ylabel(app.UIAxesSpeed, 'Speed (rpm)')
            zlabel(app.UIAxesSpeed, 'Z')
            app.UIAxesTorque = uiaxes(app.ScopesTab);
            title(app.UIAxesTorque, 'Torque')
            xlabel(app.UIAxesTorque, 'time (s)')
            ylabel(app.UIAxesTorque, 'Torque (Nm)')
            zlabel(app.UIAxesTorque, 'Z')
            app.UIAxesPressure = uiaxes(app.ScopesTab);
            title(app.UIAxesPressure, 'Pressure')
            xlabel(app.UIAxesPressure, 'time (s)')
            ylabel(app.UIAxesPressure, 'Pressure (bar)')
            zlabel(app.UIAxesPressure, 'Z')
            app.UIAxesSwashPlate = uiaxes(app.ScopesTab);
            title(app.UIAxesSwashPlate, 'Swash Plate')
            xlabel(app.UIAxesSwashPlate, 'time (s)')
            ylabel(app.UIAxesSwashPlate, 'Ctrl Signal')
            zlabel(app.UIAxesSwashPlate, 'Z')
            app.HILPowerTab = uitab(app.TabGroup);
            app.HILPowerTab.Title = 'HIL Power';
            app.UIAxesPower = uiaxes(app.HILPowerTab);
            title(app.UIAxesPower, 'Power')
            xlabel(app.UIAxesPower, 'time (s)')
            ylabel(app.UIAxesPower, 'Power (kW)')
            zlabel(app.UIAxesPower, 'Z')
            app.PowerkWGaugeLabel = uilabel(app.HILPowerTab);
            app.PowerkWGaugeLabel.Text = 'Power (kW)';
            app.HydrPowerkWGauge = uigauge(app.HILPowerTab, 'semicircular');
            app.HydrPowerkWGauge.Limits = [-10 10];
            app.AveragePowerkWGaugeLabel = uilabel(app.HILPowerTab);
            app.AveragePowerkWGaugeLabel.Text = 'Average Power (kW)';
            app.HydrAveragePowerkWGauge = uigauge(app.HILPowerTab, 'semicircular');
            app.HydrAveragePowerkWGauge.Limits = [-10 10];
            app.HydrAveragePowerkWEditField = uieditfield(app.HILPowerTab, 'numeric');
            app.HydraulicLabel = uilabel(app.HILPowerTab);
            app.HydraulicLabel.Text = 'Hydraulic';
            app.PowerkWGaugeLabel_2 = uilabel(app.HILPowerTab);
            app.PowerkWGaugeLabel_2.Text = 'Power (kW)';
            app.WaveBotPowerkWGauge = uigauge(app.HILPowerTab, 'semicircular');
            app.WaveBotPowerkWGauge.Limits = [-10 10];
            app.AveragePowerkWGaugeLabel_2 = uilabel(app.HILPowerTab);
            app.AveragePowerkWGaugeLabel_2.Text = 'Average Power (kW)';
            app.WaveBotAveragePowerkWGauge = uigauge(app.HILPowerTab, 'semicircular');
            app.WaveBotAveragePowerkWGauge.Limits = [-10 10];
            app.PowerkWGaugeLabel_4 = uilabel(app.HILPowerTab);
            app.PowerkWGaugeLabel_4.Text = 'Power (kW)';
            app.GenSidePowerkWGauge = uigauge(app.HILPowerTab, 'semicircular');
            app.GenSidePowerkWGauge.Limits = [-10 10];
            app.AveragePowerkWGaugeLabel_4 = uilabel(app.HILPowerTab);
            app.AveragePowerkWGaugeLabel_4.Text = 'Average Power (kW)';
            app.GenSideAveragePowerkWGauge = uigauge(app.HILPowerTab, 'semicircular');
            app.GenSideAveragePowerkWGauge.Limits = [-10 10];
            app.PowerkWGaugeLabel_3 = uilabel(app.HILPowerTab);
            app.PowerkWGaugeLabel_3.Text = 'Power (kW)';
            app.ExcSidePowerkWGauge = uigauge(app.HILPowerTab, 'semicircular');
            app.ExcSidePowerkWGauge.Limits = [-10 10];
            app.AveragePowerkWGaugeLabel_3 = uilabel(app.HILPowerTab);
            app.AveragePowerkWGaugeLabel_3.Text = 'Average Power (kW)';
            app.ExcSideAveragePowerkWGauge = uigauge(app.HILPowerTab, 'semicircular');
            app.ExcSideAveragePowerkWGauge.Limits = [-10 10];
            app.WaveBotAveragePowerkWEditField = uieditfield(app.HILPowerTab, 'numeric');
            app.ExcSideAveragePowerkWEditField = uieditfield(app.HILPowerTab, 'numeric');
            app.GenSideAveragePowerkWEditField = uieditfield(app.HILPowerTab, 'numeric');
            app.WaveBotLabel = uilabel(app.HILPowerTab);
            app.WaveBotLabel.Text = 'WaveBot';
            app.ExciationSideLabel = uilabel(app.HILPowerTab);
            app.ExciationSideLabel.Text = 'Exciation Side';
            app.GeneratorSideLabel = uilabel(app.HILPowerTab);
            app.GeneratorSideLabel.Text = 'Generator Side';
            app.AC880DriveTab = uitab(app.TabGroup);
            app.AC880DriveTab.Title = 'AC880 Drive';
            app.GridLayout = uigridlayout(app.AC880DriveTab);
            app.acs880MotorCurrentEditField = uieditfield(app.GridLayout, 'numeric');
            app.acs880MotorCurrentEditField.ValueDisplayFormat = '%0.2f A';
            app.MotorCurrentEditFieldLabel = uilabel(app.GridLayout);
            app.MotorCurrentEditFieldLabel.Text = 'Motor Current';
            app.acs880FrequencyEditField = uieditfield(app.GridLayout, 'numeric');
            app.acs880FrequencyEditField.ValueDisplayFormat = '%.2f Hz';
            app.FrequencyEditFieldLabel = uilabel(app.GridLayout);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.acs880PowerEditField = uieditfield(app.GridLayout, 'numeric');
            app.acs880PowerEditField.ValueDisplayFormat = '%.2f W';
            app.PowerEditFieldLabel = uilabel(app.GridLayout);
            app.PowerEditFieldLabel.Text = 'Power';
            app.acs880MotorVoltageEditField = uieditfield(app.GridLayout, 'numeric');
            app.acs880MotorVoltageEditField.ValueDisplayFormat = '%0.2f V';
            app.MotorVoltageEditFieldLabel = uilabel(app.GridLayout);
            app.MotorVoltageEditFieldLabel.Text = 'Motor Voltage';
            app.Off1LampLabel = uilabel(app.GridLayout);
            app.Off1LampLabel.Text = 'Off1';
            app.acs880Off1Lamp = uilamp(app.GridLayout);
            app.Off2LampLabel = uilabel(app.GridLayout);
            app.Off2LampLabel.Text = 'Off2';
            app.acs880Off2Lamp = uilamp(app.GridLayout);
            app.Off3LampLabel = uilabel(app.GridLayout);
            app.Off3LampLabel.Text = 'Off3';
            app.acs880Off3Lamp = uilamp(app.GridLayout);
            app.EnableOperationLampLabel = uilabel(app.GridLayout);
            app.EnableOperationLampLabel.Text = 'Enable Operation';
            app.acs880EnableOperationLamp = uilamp(app.GridLayout);
            app.RampOutZeroLabel = uilabel(app.GridLayout);
            app.RampOutZeroLabel.Text = 'Ramp Out Zero';
            app.acs880RampOutZeroLamp = uilamp(app.GridLayout);
            app.RampHoldLampLabel = uilabel(app.GridLayout);
            app.RampHoldLampLabel.Text = 'Ramp Hold';
            app.acs880RampHoldLamp = uilamp(app.GridLayout);
            app.RampInZeroLabel = uilabel(app.GridLayout);
            app.RampInZeroLabel.Text = 'Ramp In Zero';
            app.acs880RampInZeroLamp = uilamp(app.GridLayout);
            app.Inching1LampLabel = uilabel(app.GridLayout);
            app.Inching1LampLabel.Text = 'Inching 1';
            app.acs880Inching1Lamp = uilamp(app.GridLayout);
            app.Inching2LampLabel = uilabel(app.GridLayout);
            app.Inching2LampLabel.Text = 'Inching 2';
            app.acs880Inching2Lamp = uilamp(app.GridLayout);
            app.RemoteCMDLampLabel = uilabel(app.GridLayout);
            app.RemoteCMDLampLabel.Text = 'Remote CMD';
            app.acs880RemoteCMDLamp = uilamp(app.GridLayout);
            app.ExternalCtrlLocLabel = uilabel(app.GridLayout);
            app.ExternalCtrlLocLabel.Text = 'External Ctrl Loc';
            app.acs880ExternalCtrlLocLamp = uilamp(app.GridLayout);
            app.ResetLampLabel = uilabel(app.GridLayout);
            app.ResetLampLabel.Text = 'Reset';
            app.acs880ResetLamp = uilamp(app.GridLayout);
            app.ACS880EK3443Tab = uitab(app.TabGroup);
            app.ACS880EK3443Tab.Title = 'ACS880 EK3443';
            app.GridLayout3 = uigridlayout(app.ACS880EK3443Tab);
            app.FrequencyEditFieldLabel_3 = uilabel(app.GridLayout3);
            app.FrequencyEditFieldLabel_3.Text = 'Frequency';
            app.acs880FreqEditField = uieditfield(app.GridLayout3, 'numeric');
            app.acs880FreqEditField.ValueDisplayFormat = '%11.4g Hz';
            app.PowerFactorEditFieldLabel = uilabel(app.GridLayout3);
            app.PowerFactorEditFieldLabel.Text = 'Power Factor';
            app.acs880PowerFactorEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ActivePowerEditFieldLabel = uilabel(app.GridLayout3);
            app.ActivePowerEditFieldLabel.Text = 'Active Power';
            app.acs880ActivePowerEditField = uieditfield(app.GridLayout3, 'numeric');
            app.L1L2VEditFieldLabel = uilabel(app.GridLayout3);
            app.L1L2VEditFieldLabel.Text = 'L1-L2 V';
            app.acs880L1L2VEditField = uieditfield(app.GridLayout3, 'numeric');
            app.acs880L1L2VEditField.ValueDisplayFormat = '%11.4g V';
            app.L2L3VEditFieldLabel = uilabel(app.GridLayout3);
            app.L2L3VEditFieldLabel.Text = 'L2-L3 V';
            app.acs880L2L3VEditField = uieditfield(app.GridLayout3, 'numeric');
            app.acs880L2L3VEditField.ValueDisplayFormat = '%11.4g V';
            app.L3L1VEditFieldLabel = uilabel(app.GridLayout3);
            app.L3L1VEditFieldLabel.Text = 'L3-L1 V';
            app.acs880L3L1VEditField = uieditfield(app.GridLayout3, 'numeric');
            app.acs880L3L1VEditField.ValueDisplayFormat = '%11.4g V';
            app.L1VEditFieldLabel = uilabel(app.GridLayout3);
            app.L1VEditFieldLabel.Text = 'L1 V';
            app.acs880L1VEditField = uieditfield(app.GridLayout3, 'numeric');
            app.acs880L1VEditField.ValueDisplayFormat = '%11.4g V';
            app.L1ULabel = uilabel(app.GridLayout3);
            app.L1ULabel.Text = 'L1 U';
            app.acs880L1UEditField = uieditfield(app.GridLayout3, 'numeric');
            app.acs880L1UEditField.ValueDisplayFormat = '%11.4g A';
            app.L2VEditFieldLabel = uilabel(app.GridLayout3);
            app.L2VEditFieldLabel.Text = 'L2 V';
            app.acs880L2VEditField = uieditfield(app.GridLayout3, 'numeric');
            app.acs880L2VEditField.ValueDisplayFormat = '%11.4g V';
            app.L2UEditField_2Label = uilabel(app.GridLayout3);
            app.L2UEditField_2Label.Text = 'L2 U';
            app.acs880L2UEditField = uieditfield(app.GridLayout3, 'numeric');
            app.acs880L2UEditField.ValueDisplayFormat = '%11.4g A';
            app.L3VEditFieldLabel = uilabel(app.GridLayout3);
            app.L3VEditFieldLabel.Text = 'L3 V';
            app.acs880L3VEditField = uieditfield(app.GridLayout3, 'numeric');
            app.acs880L3VEditField.ValueDisplayFormat = '%11.4g V';
            app.L3UEditFieldLabel = uilabel(app.GridLayout3);
            app.L3UEditFieldLabel.Text = 'L3 U';
            app.acs880L3UEditField = uieditfield(app.GridLayout3, 'numeric');
            app.acs880L3UEditField.ValueDisplayFormat = '%11.4g A';
            app.AC800DriveTab = uitab(app.TabGroup);
            app.AC800DriveTab.Title = 'AC800 Drive';
            app.GridLayoutACS880Power = uigridlayout(app.AC800DriveTab);
            app.acs800TemperatureEditField = uieditfield(app.GridLayoutACS880Power, 'numeric');
            app.acs800TemperatureEditField.ValueDisplayFormat = '%0.2f %% IGBT';
            app.TemperatureLabel = uilabel(app.GridLayoutACS880Power);
            app.TemperatureLabel.Text = 'Temperature';
            app.acs800FrequencyEditField = uieditfield(app.GridLayoutACS880Power, 'numeric');
            app.acs800FrequencyEditField.ValueDisplayFormat = '%.2f Hz';
            app.FrequencyEditFieldLabel_2 = uilabel(app.GridLayoutACS880Power);
            app.FrequencyEditFieldLabel_2.Text = 'Frequency';
            app.acs800PowerEditField = uieditfield(app.GridLayoutACS880Power, 'numeric');
            app.acs800PowerEditField.ValueDisplayFormat = '%.2f W';
            app.PowerEditFieldLabel_2 = uilabel(app.GridLayoutACS880Power);
            app.PowerEditFieldLabel_2.Text = 'Power';
            app.acs800dcBusVoltageEditField = uieditfield(app.GridLayoutACS880Power, 'numeric');
            app.acs800dcBusVoltageEditField.ValueDisplayFormat = '%0.2f V';
            app.DCBusVoltageLabel = uilabel(app.GridLayoutACS880Power);
            app.DCBusVoltageLabel.Text = 'DC Bus Voltage';
            app.Off1LampLabel_2 = uilabel(app.GridLayoutACS880Power);
            app.Off1LampLabel_2.Text = 'Off1';
            app.acs800Off1Lamp = uilamp(app.GridLayoutACS880Power);
            app.Off2LampLabel_2 = uilabel(app.GridLayoutACS880Power);
            app.Off2LampLabel_2.Text = 'Off2';
            app.acs800Off2Lamp = uilamp(app.GridLayoutACS880Power);
            app.Off3LampLabel_2 = uilabel(app.GridLayoutACS880Power);
            app.Off3LampLabel_2.Text = 'Off3';
            app.acs800Off3Lamp = uilamp(app.GridLayoutACS880Power);
            app.EnableOperationLampLabel_2 = uilabel(app.GridLayoutACS880Power);
            app.EnableOperationLampLabel_2.Text = 'Enable Operation';
            app.acs800EnableOperationLamp = uilamp(app.GridLayoutACS880Power);
            app.RampOutZeroLabel_2 = uilabel(app.GridLayoutACS880Power);
            app.RampOutZeroLabel_2.Text = 'Ramp Out Zero';
            app.acs800RampOutZeroLamp = uilamp(app.GridLayoutACS880Power);
            app.RampHoldLampLabel_2 = uilabel(app.GridLayoutACS880Power);
            app.RampHoldLampLabel_2.Text = 'Ramp Hold';
            app.acs800RampHoldLamp = uilamp(app.GridLayoutACS880Power);
            app.RampInZeroLabel_2 = uilabel(app.GridLayoutACS880Power);
            app.RampInZeroLabel_2.Text = 'Ramp In Zero';
            app.acs800RampInZeroLamp = uilamp(app.GridLayoutACS880Power);
            app.Inching1LampLabel_2 = uilabel(app.GridLayoutACS880Power);
            app.Inching1LampLabel_2.Text = 'Inching 1';
            app.acs800Inching1Lamp = uilamp(app.GridLayoutACS880Power);
            app.Inching2LampLabel_2 = uilabel(app.GridLayoutACS880Power);
            app.Inching2LampLabel_2.Text = 'Inching 2';
            app.acs800Inching2Lamp = uilamp(app.GridLayoutACS880Power);
            app.RemoteCMDLampLabel_2 = uilabel(app.GridLayoutACS880Power);
            app.RemoteCMDLampLabel_2.Text = 'Remote CMD';
            app.acs800RemoteCMDLamp = uilamp(app.GridLayoutACS880Power);
            app.ExternalCtrlLocLabel_2 = uilabel(app.GridLayoutACS880Power);
            app.ExternalCtrlLocLabel_2.Text = 'External Ctrl Loc';
            app.acs800ExternalCtrlLocLamp = uilamp(app.GridLayoutACS880Power);
            app.ResetLampLabel_2 = uilabel(app.GridLayoutACS880Power);
            app.ResetLampLabel_2.Text = 'Reset';
            app.acs800ResetLamp = uilamp(app.GridLayoutACS880Power);
            app.ACS800EK3443Tab = uitab(app.TabGroup);
            app.ACS800EK3443Tab.Title = 'ACS800 EK3443';
            app.GridLayoutACS800Power = uigridlayout(app.ACS800EK3443Tab);
            app.FrequencyEditFieldLabel_4 = uilabel(app.GridLayoutACS800Power);
            app.FrequencyEditFieldLabel_4.Text = 'Frequency';
            app.acs800FreqEditField = uieditfield(app.GridLayoutACS800Power, 'numeric');
            app.acs800FreqEditField.ValueDisplayFormat = '%11.4g Hz';
            app.PowerFactorEditFieldLabel_2 = uilabel(app.GridLayoutACS800Power);
            app.PowerFactorEditFieldLabel_2.Text = 'Power Factor';
            app.acs800PowerFactorEditField = uieditfield(app.GridLayoutACS800Power, 'numeric');
            app.ActivePowerEditFieldLabel_2 = uilabel(app.GridLayoutACS800Power);
            app.ActivePowerEditFieldLabel_2.Text = 'Active Power';
            app.acs800ActivePowerEditField = uieditfield(app.GridLayoutACS800Power, 'numeric');
            app.L1L2VEditFieldLabel_2 = uilabel(app.GridLayoutACS800Power);
            app.L1L2VEditFieldLabel_2.Text = 'L1-L2 V';
            app.acs800L1L2VEditField = uieditfield(app.GridLayoutACS800Power, 'numeric');
            app.acs800L1L2VEditField.ValueDisplayFormat = '%11.4g V';
            app.L2L3VEditFieldLabel_2 = uilabel(app.GridLayoutACS800Power);
            app.L2L3VEditFieldLabel_2.Text = 'L2-L3 V';
            app.acs800L2L3VEditField = uieditfield(app.GridLayoutACS800Power, 'numeric');
            app.acs800L2L3VEditField.ValueDisplayFormat = '%11.4g V';
            app.L3L1VEditFieldLabel_2 = uilabel(app.GridLayoutACS800Power);
            app.L3L1VEditFieldLabel_2.Text = 'L3-L1 V';
            app.acs800L3L1VEditField = uieditfield(app.GridLayoutACS800Power, 'numeric');
            app.acs800L3L1VEditField.ValueDisplayFormat = '%11.4g V';
            app.L1VEditFieldLabel_2 = uilabel(app.GridLayoutACS800Power);
            app.L1VEditFieldLabel_2.Text = 'L1 V';
            app.acs800L1VEditField = uieditfield(app.GridLayoutACS800Power, 'numeric');
            app.acs800L1VEditField.ValueDisplayFormat = '%11.4g V';
            app.L2UEditFieldLabel_2 = uilabel(app.GridLayoutACS800Power);
            app.L2UEditFieldLabel_2.Text = 'L2 U';
            app.acs800L1UEditField = uieditfield(app.GridLayoutACS800Power, 'numeric');
            app.acs800L1UEditField.ValueDisplayFormat = '%11.4g A';
            app.L2VEditFieldLabel_2 = uilabel(app.GridLayoutACS800Power);
            app.L2VEditFieldLabel_2.Text = 'L2 V';
            app.acs800L2VEditField = uieditfield(app.GridLayoutACS800Power, 'numeric');
            app.acs800L2VEditField.ValueDisplayFormat = '%11.4g V';
            app.L2UEditField_2Label_2 = uilabel(app.GridLayoutACS800Power);
            app.L2UEditField_2Label_2.Text = 'L2 U';
            app.acs800L2UEditField = uieditfield(app.GridLayoutACS800Power, 'numeric');
            app.acs800L2UEditField.ValueDisplayFormat = '%11.4g A';
            app.L3VEditFieldLabel_2 = uilabel(app.GridLayoutACS800Power);
            app.L3VEditFieldLabel_2.Text = 'L3 V';
            app.acs800L3VEditField = uieditfield(app.GridLayoutACS800Power, 'numeric');
            app.acs800L3VEditField.ValueDisplayFormat = '%11.4g V';
            app.L3UEditFieldLabel_2 = uilabel(app.GridLayoutACS800Power);
            app.L3UEditFieldLabel_2.Text = 'L3 U';
            app.acs800L3UEditField = uieditfield(app.GridLayoutACS800Power, 'numeric');
            app.acs800L3UEditField.ValueDisplayFormat = '%11.4g A';
            app.HBMTab = uitab(app.TabGroup);
            app.HBMTab.Title = 'HBM';
            app.GridLayoutHBM = uigridlayout(app.HBMTab);
            app.LatencyEditFieldLabel = uilabel(app.GridLayoutHBM);
            app.LatencyEditFieldLabel.Text = 'Latency';
            app.hbmLatencyEditField = uieditfield(app.GridLayoutHBM, 'numeric');
            app.AcqTimeEditFieldLabel = uilabel(app.GridLayoutHBM);
            app.AcqTimeEditFieldLabel.Text = 'Acq. Time';
            app.hbmAcqTimeEditField = uieditfield(app.GridLayoutHBM, 'numeric');
            app.hbmAcqTimeEditField.ValueDisplayFormat = '%.2f s';
            app.AcqStateEditFieldLabel = uilabel(app.GridLayoutHBM);
            app.AcqStateEditFieldLabel.Text = 'Acq. State';
            app.hbmAcqStateEditField = uieditfield(app.GridLayoutHBM, 'numeric');
            app.DriveOutU1EditFieldLabel = uilabel(app.GridLayoutHBM);
            app.DriveOutU1EditFieldLabel.Text = 'Drive Out U1';
            app.hbmDriveOutU1EditField = uieditfield(app.GridLayoutHBM, 'numeric');
            app.hbmDriveOutU1EditField.ValueDisplayFormat = '%0.2f V';
            app.DriveOutI1EditFieldLabel = uilabel(app.GridLayoutHBM);
            app.DriveOutI1EditFieldLabel.Text = 'Drive Out I1';
            app.hbmDriveOutI1EditField = uieditfield(app.GridLayoutHBM, 'numeric');
            app.hbmDriveOutI1EditField.ValueDisplayFormat = '%0.2f A';
            app.DriveOutP1EditFieldLabel = uilabel(app.GridLayoutHBM);
            app.DriveOutP1EditFieldLabel.Text = 'Drive Out P1';
            app.hbmDriveOutP1EditField = uieditfield(app.GridLayoutHBM, 'numeric');
            app.hbmDriveOutP1EditField.ValueDisplayFormat = '%0.2f W';
            app.DriveOutU2EditFieldLabel = uilabel(app.GridLayoutHBM);
            app.DriveOutU2EditFieldLabel.Text = 'Drive Out U2';
            app.hbmDriveOutU2EditField = uieditfield(app.GridLayoutHBM, 'numeric');
            app.hbmDriveOutU2EditField.ValueDisplayFormat = '%0.2f V';
            app.DriveOutI2EditFieldLabel = uilabel(app.GridLayoutHBM);
            app.DriveOutI2EditFieldLabel.Text = 'Drive Out I2';
            app.hbmDriveOutI2EditField = uieditfield(app.GridLayoutHBM, 'numeric');
            app.hbmDriveOutI2EditField.ValueDisplayFormat = '%0.2f A';
            app.DriveOutP2EditFieldLabel = uilabel(app.GridLayoutHBM);
            app.DriveOutP2EditFieldLabel.Text = 'Drive Out P2';
            app.hbmDriveOutP2EditField = uieditfield(app.GridLayoutHBM, 'numeric');
            app.hbmDriveOutP2EditField.ValueDisplayFormat = '%0.2f W';
            app.DriveOutU3EditFieldLabel = uilabel(app.GridLayoutHBM);
            app.DriveOutU3EditFieldLabel.Text = 'Drive Out U3';
            app.hbmDriveOutU3EditField = uieditfield(app.GridLayoutHBM, 'numeric');
            app.hbmDriveOutU3EditField.ValueDisplayFormat = '%0.2f V';
            app.DriveOutI3EditFieldLabel = uilabel(app.GridLayoutHBM);
            app.DriveOutI3EditFieldLabel.Text = 'Drive Out I3';
            app.hbmDriveOutI3EditField = uieditfield(app.GridLayoutHBM, 'numeric');
            app.hbmDriveOutI3EditField.ValueDisplayFormat = '%0.2f A';
            app.DriveOutP3EditFieldLabel = uilabel(app.GridLayoutHBM);
            app.DriveOutP3EditFieldLabel.Text = 'Drive Out P3';
            app.hbmDriveOutP3EditField = uieditfield(app.GridLayoutHBM, 'numeric');
            app.hbmDriveOutP3EditField.ValueDisplayFormat = '%0.2f W';
            app.DriveOutPEditFieldLabel = uilabel(app.GridLayoutHBM);
            app.DriveOutPEditFieldLabel.Text = 'Drive Out P';
            app.hbmDriveOutPEditField = uieditfield(app.GridLayoutHBM, 'numeric');
            app.hbmDriveOutPEditField.ValueDisplayFormat = '%0.2f W';
            app.DebugTab = uitab(app.TabGroup);
            app.DebugTab.Title = 'Debug';
            app.ExpTypeEditFieldLabel = uilabel(app.DebugTab);
            app.ExpTypeEditFieldLabel.Text = 'Exp Type';
            app.ExpTypeEditField = uieditfield(app.DebugTab, 'text');
            app.AbsEncoderCountsEditFieldLabel = uilabel(app.DebugTab);
            app.AbsEncoderCountsEditFieldLabel.Text = 'Abs Encoder Counts';
            app.AbsEncoderCountsEditField = uieditfield(app.DebugTab, 'numeric');
            app.AbsEncoderPosEditFieldLabel = uilabel(app.DebugTab);
            app.AbsEncoderPosEditFieldLabel.Text = 'Abs Encoder Pos';
            app.AbsEncoderPosEditField = uieditfield(app.DebugTab, 'numeric');
            app.AbsEncoderPosEditField.ValueDisplayFormat = '%11.4g rad';
            app.ACS880SpeedPGainSpinnerLabel = uilabel(app.DebugTab);
            app.ACS880SpeedPGainSpinnerLabel.Text = 'ACS880 Speed P Gain';
            app.ACS880SpeedPGainSpinner = uispinner(app.DebugTab);
            app.ACS880SpeedPGainSpinner.ValueChangedFcn = createCallbackFcn(app, @ACS880SpeedPGainSpinnerValueChanged, true);
            app.ACS880SpeedIGainSpinnerLabel = uilabel(app.DebugTab);
            app.ACS880SpeedIGainSpinnerLabel.Text = 'ACS880 Speed I Gain';
            app.ACS880SpeedIGainSpinner = uispinner(app.DebugTab);
            app.ACS880SpeedIGainSpinner.ValueChangedFcn = createCallbackFcn(app, @ACS880SpeedIGainSpinnerValueChanged, true);
            app.AbsEncoderTurnsEditFieldLabel = uilabel(app.DebugTab);
            app.AbsEncoderTurnsEditFieldLabel.Text = 'Abs Encoder Turns';
            app.AbsEncoderTurnsEditField = uieditfield(app.DebugTab, 'numeric');
            app.AbsLastCountsEditFieldLabel = uilabel(app.DebugTab);
            app.AbsLastCountsEditFieldLabel.Text = 'Abs Last Counts';
            app.AbsLastCountsEditField = uieditfield(app.DebugTab, 'numeric');
            app.ACS880ExcitationHydraulicSimulationPanel = uipanel(app.WindEmulatorFigure);
            app.ACS880ExcitationHydraulicSimulationPanel.Title = 'ACS880 (Excitation + Hydraulic Simulation)';
            app.acs880TorqueEditField = uieditfield(app.ACS880ExcitationHydraulicSimulationPanel, 'numeric');
            app.acs880TorqueEditField.ValueDisplayFormat = '%.2f Nm';
            app.acs880SpeedEditField = uieditfield(app.ACS880ExcitationHydraulicSimulationPanel, 'numeric');
            app.acs880SpeedEditField.ValueDisplayFormat = '%.2f rpm';
            app.acs880TorqueGauge = uigauge(app.ACS880ExcitationHydraulicSimulationPanel, 'circular');
            app.acs880TorqueGauge.Limits = [-80 80];
            app.TorqueActualLabel = uilabel(app.ACS880ExcitationHydraulicSimulationPanel);
            app.TorqueActualLabel.Text = 'Torque Actual';
            app.acs880StatusWordEditField = uieditfield(app.ACS880ExcitationHydraulicSimulationPanel, 'text');
            app.StatusWordLabel = uilabel(app.ACS880ExcitationHydraulicSimulationPanel);
            app.StatusWordLabel.Text = 'Status Word';
            app.acs880CtrlWordEditField = uieditfield(app.ACS880ExcitationHydraulicSimulationPanel, 'text');
            app.CtrlWordEditFieldLabel = uilabel(app.ACS880ExcitationHydraulicSimulationPanel);
            app.CtrlWordEditFieldLabel.Text = 'Ctrl Word';
            app.StatusPanel = uipanel(app.ACS880ExcitationHydraulicSimulationPanel);
            app.StatusPanel.Title = 'Status';
            app.GridLayout2 = uigridlayout(app.StatusPanel);
            app.RdyOnLampLabel = uilabel(app.GridLayout2);
            app.RdyOnLampLabel.Text = 'Rdy On';
            app.acs880RdyOnLamp = uilamp(app.GridLayout2);
            app.RdyRunLampLabel = uilabel(app.GridLayout2);
            app.RdyRunLampLabel.Text = 'Rdy Run';
            app.acs880RdyRunLamp = uilamp(app.GridLayout2);
            app.RemoteLampLabel = uilabel(app.GridLayout2);
            app.RemoteLampLabel.Text = 'Remote';
            app.acs880RemoteLamp = uilamp(app.GridLayout2);
            app.TrippedLampLabel = uilabel(app.GridLayout2);
            app.TrippedLampLabel.Text = 'Tripped';
            app.acs880TrippedLamp = uilamp(app.GridLayout2);
            app.InhibitedLampLabel = uilabel(app.GridLayout2);
            app.InhibitedLampLabel.Text = 'Inhibited';
            app.acs880InhibitedLamp = uilamp(app.GridLayout2);
            app.AlarmLampLabel = uilabel(app.GridLayout2);
            app.AlarmLampLabel.Text = 'Alarm';
            app.acs880AlarmLamp = uilamp(app.GridLayout2);
            app.acs880InternalStateTextArea = uitextarea(app.ACS880ExcitationHydraulicSimulationPanel);
            app.acs880PowerUp = uibutton(app.ACS880ExcitationHydraulicSimulationPanel, 'push');
            app.acs880PowerUp.ButtonPushedFcn = createCallbackFcn(app, @acs880PowerUpButtonPushed, true);
            app.acs880PowerUp.Icon = 'up-arrow.png';
            app.acs880PowerUp.Text = '';
            app.acs880PowerDown = uibutton(app.ACS880ExcitationHydraulicSimulationPanel, 'push');
            app.acs880PowerDown.ButtonPushedFcn = createCallbackFcn(app, @acs880PowerDownButtonPushed, true);
            app.acs880PowerDown.Icon = 'down-arrow.png';
            app.acs880PowerDown.Text = '';
            app.TorqueCmdGaugeLabel = uilabel(app.ACS880ExcitationHydraulicSimulationPanel);
            app.TorqueCmdGaugeLabel.Text = 'Torque Cmd';
            app.acs880TorqueCmdGauge = uigauge(app.ACS880ExcitationHydraulicSimulationPanel, 'circular');
            app.acs880TorqueCmdGauge.Limits = [-80 80];
            app.acs880TorqueCmdEditField = uieditfield(app.ACS880ExcitationHydraulicSimulationPanel, 'numeric');
            app.acs880TorqueCmdEditField.ValueDisplayFormat = '%.2f Nm';
            app.SpeedLabel = uilabel(app.ACS880ExcitationHydraulicSimulationPanel);
            app.SpeedLabel.Text = 'Speed';
            app.acs880SpeedGauge = uigauge(app.ACS880ExcitationHydraulicSimulationPanel, 'circular');
            app.acs880SpeedGauge.Limits = [-1500 1500];
            app.acs880SpeedGauge.MajorTicks = [-1500 -750 0 750 1500];
            app.ACS800GeneratorPanel = uipanel(app.WindEmulatorFigure);
            app.ACS800GeneratorPanel.Title = 'ACS800 (Generator)';
            app.acs800TorqueEditField = uieditfield(app.ACS800GeneratorPanel, 'numeric');
            app.acs800TorqueEditField.ValueDisplayFormat = '%.2f Nm';
            app.acs800SpeedEditField = uieditfield(app.ACS800GeneratorPanel, 'numeric');
            app.acs800SpeedEditField.ValueDisplayFormat = '%.2f rpm';
            app.acs800SpeedGauge = uigauge(app.ACS800GeneratorPanel, 'circular');
            app.acs800SpeedGauge.Limits = [-1500 1500];
            app.acs800SpeedGauge.MajorTicks = [-1500 -750 0 750 1500];
            app.SpeedGaugeLabel_2 = uilabel(app.ACS800GeneratorPanel);
            app.SpeedGaugeLabel_2.Text = 'Speed';
            app.acs800TorqueGauge = uigauge(app.ACS800GeneratorPanel, 'circular');
            app.acs800TorqueGauge.Limits = [-80 80];
            app.TorqueActualLabel_2 = uilabel(app.ACS800GeneratorPanel);
            app.TorqueActualLabel_2.Text = 'Torque Actual';
            app.acs800StatusWordEditField = uieditfield(app.ACS800GeneratorPanel, 'text');
            app.StatusWordLabel_2 = uilabel(app.ACS800GeneratorPanel);
            app.StatusWordLabel_2.Text = 'Status Word';
            app.acs800CtrlWordEditField = uieditfield(app.ACS800GeneratorPanel, 'text');
            app.CtrlWordEditFieldLabel_2 = uilabel(app.ACS800GeneratorPanel);
            app.CtrlWordEditFieldLabel_2.Text = 'Ctrl Word';
            app.acs800StatusPanel = uipanel(app.ACS800GeneratorPanel);
            app.acs800StatusPanel.Title = 'Status';
            app.GridLayout2_2 = uigridlayout(app.acs800StatusPanel);
            app.RdyOnLampLabel_2 = uilabel(app.GridLayout2_2);
            app.RdyOnLampLabel_2.Text = 'Rdy On';
            app.acs800RdyOnLamp = uilamp(app.GridLayout2_2);
            app.RdyRunLampLabel_2 = uilabel(app.GridLayout2_2);
            app.RdyRunLampLabel_2.Text = 'Rdy Run';
            app.acs800RdyRunLamp = uilamp(app.GridLayout2_2);
            app.RemoteLampLabel_2 = uilabel(app.GridLayout2_2);
            app.RemoteLampLabel_2.Text = 'Remote';
            app.acs800RemoteLamp = uilamp(app.GridLayout2_2);
            app.TrippedLampLabel_2 = uilabel(app.GridLayout2_2);
            app.TrippedLampLabel_2.Text = 'Tripped';
            app.acs800TrippedLamp = uilamp(app.GridLayout2_2);
            app.InhibitedLampLabel_2 = uilabel(app.GridLayout2_2);
            app.InhibitedLampLabel_2.Text = 'Inhibited';
            app.acs800InhibitedLamp = uilamp(app.GridLayout2_2);
            app.AlarmLampLabel_2 = uilabel(app.GridLayout2_2);
            app.AlarmLampLabel_2.Text = 'Alarm';
            app.acs800AlarmLamp = uilamp(app.GridLayout2_2);
            app.acs800InternalStateTextArea = uitextarea(app.ACS800GeneratorPanel);
            app.acs800PowerUp = uibutton(app.ACS800GeneratorPanel, 'push');
            app.acs800PowerUp.ButtonPushedFcn = createCallbackFcn(app, @acs800PowerUpButtonPushed, true);
            app.acs800PowerUp.Icon = 'up-arrow.png';
            app.acs800PowerUp.Text = '';
            app.acs800PowerDown = uibutton(app.ACS800GeneratorPanel, 'push');
            app.acs800PowerDown.ButtonPushedFcn = createCallbackFcn(app, @acs800PowerDownButtonPushed, true);
            app.acs800PowerDown.Icon = 'down-arrow.png';
            app.acs800PowerDown.Text = '';
            app.TorqueCmdGaugeLabel_2 = uilabel(app.ACS800GeneratorPanel);
            app.TorqueCmdGaugeLabel_2.Text = 'Torque Cmd';
            app.acs800TorqueCmdGauge = uigauge(app.ACS800GeneratorPanel, 'circular');
            app.acs800TorqueCmdGauge.Limits = [-80 80];
            app.acs800TorqueCmdEditField = uieditfield(app.ACS800GeneratorPanel, 'numeric');
            app.acs800TorqueCmdEditField.ValueDisplayFormat = '%.2f Nm';
            app.HILExperimentControlPanel = uipanel(app.WindEmulatorFigure);
            app.HILExperimentControlPanel.Title = 'HIL Experiment Control';
            app.FexEditField = uieditfield(app.HILExperimentControlPanel, 'numeric');
            app.FexEditField.ValueDisplayFormat = '%.2f N';
            app.FexGaugeLabel = uilabel(app.HILExperimentControlPanel);
            app.FexGaugeLabel.Text = 'Fex';
            app.FexGauge = uigauge(app.HILExperimentControlPanel, 'circular');
            app.FexGauge.Limits = [-5000 5000];
            app.SpeedRefSpinnerLabel = uilabel(app.HILExperimentControlPanel);
            app.SpeedRefSpinnerLabel.Text = 'Speed Ref';
            app.SpeedRefSpinner = uispinner(app.HILExperimentControlPanel);
            app.SpeedRefSpinner.ValueDisplayFormat = '%11.4g rpm';
            app.SpeedRefSpinner.ValueChangedFcn = createCallbackFcn(app, @SpeedRefSpinnerValueChanged, true);
            app.KDampingEditFieldLabel = uilabel(app.HILExperimentControlPanel);
            app.KDampingEditFieldLabel.Text = 'K Damping';
            app.KDampingEditField = uieditfield(app.HILExperimentControlPanel, 'numeric');
            app.KDampingEditField.ValueDisplayFormat = '%11.4g kN/(m/s)';
            app.KDampingEditField.Editable = 'off';
            app.KSpringEditFieldLabel = uilabel(app.HILExperimentControlPanel);
            app.KSpringEditFieldLabel.Text = 'K Spring';
            app.KSpringEditField = uieditfield(app.HILExperimentControlPanel, 'numeric');
            app.KSpringEditField.ValueDisplayFormat = '%11.4g kN/m';
            app.KSpringEditField.Editable = 'off';
            app.FexAmpEditFieldLabel = uilabel(app.HILExperimentControlPanel);
            app.FexAmpEditFieldLabel.Text = 'Fex Amp';
            app.FexAmpEditField = uieditfield(app.HILExperimentControlPanel, 'numeric');
            app.FexAmpEditField.ValueDisplayFormat = '%11.4g kN';
            app.FexAmpEditField.Editable = 'off';
            app.WaveAmpSpinnerLabel = uilabel(app.HILExperimentControlPanel);
            app.WaveAmpSpinnerLabel.Text = 'Wave Amp';
            app.WaveAmpSpinner = uispinner(app.HILExperimentControlPanel);
            app.WaveAmpSpinner.Step = 0.05;
            app.WaveAmpSpinner.ValueDisplayFormat = '%11.4g m';
            app.WaveAmpSpinner.ValueChangedFcn = createCallbackFcn(app, @WaveAmpSpinnerValueChanged, true);
            app.WavePeriodSpinnerLabel = uilabel(app.HILExperimentControlPanel);
            app.WavePeriodSpinnerLabel.Text = 'Wave Period';
            app.WavePeriodSpinner = uispinner(app.HILExperimentControlPanel);
            app.WavePeriodSpinner.Step = 0.1;
            app.WavePeriodSpinner.ValueDisplayFormat = '%11.4g s';
            app.WavePeriodSpinner.ValueChangedFcn = createCallbackFcn(app, @WavePeriodSpinnerValueChanged, true);
            app.MinSpeedRefSpinnerLabel = uilabel(app.HILExperimentControlPanel);
            app.MinSpeedRefSpinnerLabel.Text = 'Min Speed Ref';
            app.MinSpeedRefSpinner = uispinner(app.HILExperimentControlPanel);
            app.MinSpeedRefSpinner.ValueDisplayFormat = '%11.4g rpm';
            app.MinSpeedRefSpinner.ValueChangedFcn = createCallbackFcn(app, @MinSpeedRefSpinnerValueChanged, true);
            app.HPTOInternalsPanel = uipanel(app.WindEmulatorFigure);
            app.HPTOInternalsPanel.Title = 'HPTO Internals';
            app.PresbarSliderLabel = uilabel(app.HPTOInternalsPanel);
            app.PresbarSliderLabel.Text = 'Pres. (bar)';
            app.PresbarSlider = uislider(app.HPTOInternalsPanel);
            app.PresbarSlider.Limits = [0 350];
            app.CtrlSignal1GaugeLabel = uilabel(app.HPTOInternalsPanel);
            app.CtrlSignal1GaugeLabel.Text = 'Ctrl Signal 1';
            app.CtrlSignal1Gauge = uigauge(app.HPTOInternalsPanel, 'semicircular');
            app.CtrlSignal1Gauge.Limits = [-1 1];
            app.CtrlSignal2GaugeLabel = uilabel(app.HPTOInternalsPanel);
            app.CtrlSignal2GaugeLabel.Text = 'Ctrl Signal 2';
            app.CtrlSignal2Gauge = uigauge(app.HPTOInternalsPanel, 'semicircular');
            app.CtrlSignal2Gauge.Limits = [-1 1];
            app.genTorqueCmdNmGaugeLabel = uilabel(app.HPTOInternalsPanel);
            app.genTorqueCmdNmGaugeLabel.Text = 'genTorqueCmd (Nm)';
            app.genTorqueCmdNmGauge = uigauge(app.HPTOInternalsPanel, 'semicircular');
            app.genTorqueCmdNmGauge.Limits = [-100 100];
            app.HMOutputShaftTNmGaugeLabel = uilabel(app.HPTOInternalsPanel);
            app.HMOutputShaftTNmGaugeLabel.Text = 'HM Output Shaft T (Nm)';
            app.HMOutputShaftTNmGauge = uigauge(app.HPTOInternalsPanel, 'semicircular');
            app.HMOutputShaftTNmGauge.Limits = [-100 100];
            app.HMOutputPumplmGaugeLabel = uilabel(app.HPTOInternalsPanel);
            app.HMOutputPumplmGaugeLabel.Text = 'HM Output Pump (l/m)';
            app.HMOutputPumplmGauge = uigauge(app.HPTOInternalsPanel, 'semicircular');
            app.HMOutputPumplmGauge.Limits = [-60 60];
            app.ExperimentOverviewPanel = uipanel(app.WindEmulatorFigure);
            app.ExperimentOverviewPanel.Title = 'Experiment Overview';
            app.RampSliderLabel = uilabel(app.ExperimentOverviewPanel);
            app.RampSliderLabel.Text = 'Ramp';
            app.RampSlider = uislider(app.ExperimentOverviewPanel);
            app.RampSlider.Limits = [0 1];
            app.RampSlider.MajorTicks = [];
            app.RampSlider.MinorTicks = [];
            app.ExpTimeEditFieldLabel = uilabel(app.ExperimentOverviewPanel);
            app.ExpTimeEditFieldLabel.Text = 'Exp. Time';
            app.ExpTimeEditField = uieditfield(app.ExperimentOverviewPanel, 'numeric');
            app.ExpTimeEditField.ValueDisplayFormat = '%.2f s';
            app.RunCounterEditFieldLabel = uilabel(app.ExperimentOverviewPanel);
            app.RunCounterEditFieldLabel.Text = 'Run Counter';
            app.RunCounterEditField = uieditfield(app.ExperimentOverviewPanel, 'numeric');
            app.ExperimentTypeButtonGroup = uibuttongroup(app.ExperimentOverviewPanel);
            app.ExperimentTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ExperimentTypeButtonGroupSelectionChanged, true);
            app.ExperimentTypeButtonGroup.Title = 'Experiment Type';
            app.OffButton = uiradiobutton(app.ExperimentTypeButtonGroup);
            app.OffButton.Text = 'Off';
            app.OffButton.Value = true;
            app.SIDButton = uiradiobutton(app.ExperimentTypeButtonGroup);
            app.SIDButton.Text = 'SID';
            app.HILButton = uiradiobutton(app.ExperimentTypeButtonGroup);
            app.HILButton.Text = 'HIL';
            app.StartRunButton = uibutton(app.ExperimentOverviewPanel, 'push');
            app.StartRunButton.ButtonPushedFcn = createCallbackFcn(app, @StartRunButtonPushed, true);
            app.StartRunButton.Icon = 'run_24.png';
            app.StartRunButton.Text = 'Start Run';
            app.StopRunButton = uibutton(app.ExperimentOverviewPanel, 'push');
            app.StopRunButton.ButtonPushedFcn = createCallbackFcn(app, @StopRunButtonPushed, true);
            app.StopRunButton.Icon = 'stop_24.png';
            app.StopRunButton.Text = 'Stop Run';
            app.RunTimeEditFieldLabel = uilabel(app.ExperimentOverviewPanel);
            app.RunTimeEditFieldLabel.Text = 'Run Time';
            app.RunTimeEditField = uieditfield(app.ExperimentOverviewPanel, 'numeric');
            app.RunTimeEditField.ValueDisplayFormat = '%11.4g s';
            app.RunTimeEditField.ValueChangedFcn = createCallbackFcn(app, @RunTimeEditFieldValueChanged, true);
            app.RunHILLampLabel = uilabel(app.ExperimentOverviewPanel);
            app.RunHILLampLabel.Text = 'Run HIL';
            app.RunHILLamp = uilamp(app.ExperimentOverviewPanel);
            app.RunSIDLampLabel = uilabel(app.ExperimentOverviewPanel);
            app.RunSIDLampLabel.Text = 'Run SID';
            app.RunSIDLamp = uilamp(app.ExperimentOverviewPanel);
            app.ResetHILIntLampLabel = uilabel(app.ExperimentOverviewPanel);
            app.ResetHILIntLampLabel.Text = 'Reset HIL Int';
            app.ResetHILIntLamp = uilamp(app.ExperimentOverviewPanel);
            app.ResetSIDIntLampLabel = uilabel(app.ExperimentOverviewPanel);
            app.ResetSIDIntLampLabel.Text = 'Reset SID Int';
            app.ResetSIDIntLamp = uilamp(app.ExperimentOverviewPanel);
            app.SIDControlPanel = uipanel(app.WindEmulatorFigure);
            app.SIDControlPanel.Title = 'SID Control';
            app.SIDTypeButtonGroup = uibuttongroup(app.SIDControlPanel);
            app.SIDTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SIDTypeButtonGroupSelectionChanged, true);
            app.SIDTypeButtonGroup.Title = 'SID Type';
            app.OffButton_2 = uiradiobutton(app.SIDTypeButtonGroup);
            app.OffButton_2.Text = 'Off';
            app.OffButton_2.Value = true;
            app.ManualButton = uiradiobutton(app.SIDTypeButtonGroup);
            app.ManualButton.Text = 'Manual';
            app.FromFileButton = uiradiobutton(app.SIDTypeButtonGroup);
            app.FromFileButton.Text = 'FromFile';
            app.TorqueSpinnerLabel = uilabel(app.SIDControlPanel);
            app.TorqueSpinnerLabel.Text = 'Torque';
            app.TorqueSpinner = uispinner(app.SIDControlPanel);
            app.TorqueSpinner.ValueDisplayFormat = '%11.4g Nm';
            app.TorqueSpinner.ValueChangedFcn = createCallbackFcn(app, @TorqueSpinnerValueChanged, true);
            app.ManualControlInputsLabel = uilabel(app.SIDControlPanel);
            app.ManualControlInputsLabel.Text = 'Manual Control Inputs';
            app.SpeedSpinnerLabel = uilabel(app.SIDControlPanel);
            app.SpeedSpinnerLabel.Text = 'Speed';
            app.SpeedSpinner = uispinner(app.SIDControlPanel);
            app.SpeedSpinner.ValueDisplayFormat = '%11.4g rpm';
            app.SpeedSpinner.ValueChangedFcn = createCallbackFcn(app, @SpeedSpinnerValueChanged, true);
            app.FromFileLabel = uilabel(app.SIDControlPanel);
            app.FromFileLabel.Text = 'From File';
            app.NoSamplesEditFieldLabel = uilabel(app.SIDControlPanel);
            app.NoSamplesEditFieldLabel.Text = 'No. Samples';
            app.FileNoSamplesEditField = uieditfield(app.SIDControlPanel, 'numeric');
            app.FileNoSamplesEditField.ValueDisplayFormat = '%4.0f';
            app.IndexNowLabel = uilabel(app.SIDControlPanel);
            app.IndexNowLabel.Text = 'Index Now';
            app.FileIndexNowEditField = uieditfield(app.SIDControlPanel, 'numeric');
            app.FileIndexNowEditField.ValueDisplayFormat = '%4.0f';
            app.ProgressEditFieldLabel = uilabel(app.SIDControlPanel);
            app.ProgressEditFieldLabel.Text = 'Progress';
            app.FileProgressEditField = uieditfield(app.SIDControlPanel, 'numeric');
            app.FileProgressEditField.ValueDisplayFormat = '%4.1f';
            app.CaseNowLabel = uilabel(app.SIDControlPanel);
            app.CaseNowLabel.Text = 'Case Now';
            app.FileCaseNowEditField = uieditfield(app.SIDControlPanel, 'numeric');
            app.FileCaseNowEditField.ValueDisplayFormat = '%4.0f';
            app.SpeedNowEditFieldLabel = uilabel(app.SIDControlPanel);
            app.SpeedNowEditFieldLabel.Text = 'Speed Now';
            app.FileSpeedNowEditField = uieditfield(app.SIDControlPanel, 'numeric');
            app.FileSpeedNowEditField.ValueDisplayFormat = '%11.4g rpm';
            app.TorqueNowEditFieldLabel = uilabel(app.SIDControlPanel);
            app.TorqueNowEditFieldLabel.Text = 'Torque Now';
            app.FileTorqueNowEditField = uieditfield(app.SIDControlPanel, 'numeric');
            app.FileTorqueNowEditField.ValueDisplayFormat = '%11.4g Nm';
            app.ShaftSignalsPanel = uipanel(app.WindEmulatorFigure);
            app.ShaftSignalsPanel.Title = 'Shaft Signals';
            app.ShaftTorqueEditField = uieditfield(app.ShaftSignalsPanel, 'numeric');
            app.ShaftTorqueEditField.ValueDisplayFormat = '%11.4g Nm';
            app.ShaftTorqueGaugeLabel = uilabel(app.ShaftSignalsPanel);
            app.ShaftTorqueGaugeLabel.Text = 'Shaft Torque';
            app.ShaftTorqueGauge = uigauge(app.ShaftSignalsPanel, 'circular');
            app.ShaftTorqueGauge.Limits = [-80 80];
            app.ShaftSpeedGaugeLabel = uilabel(app.ShaftSignalsPanel);
            app.ShaftSpeedGaugeLabel.Text = 'Shaft Speed';
            app.ShaftSpeedGauge = uigauge(app.ShaftSignalsPanel, 'circular');
            app.ShaftSpeedGauge.Limits = [-1500 1500];
            app.ShaftSpeedEditField = uieditfield(app.ShaftSignalsPanel, 'numeric');
            app.ShaftSpeedEditField.ValueDisplayFormat = '%11.4g rpm';
            % Show the figure after all components are created
            app.WindEmulatorFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = windEmulatorStep4App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WindEmulatorFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WindEmulatorFigure)
        end
    end
end